classdef AT_OSCU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        ALabel_3                        matlab.ui.control.Label
        TransformationRatioEditFieldLabel  matlab.ui.control.Label
        TransformationRatioEditField    matlab.ui.control.NumericEditField
        EconomyinCopperEditFieldLabel   matlab.ui.control.Label
        EconomyinCopperEditField        matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        ApparentPowerEditFieldLabel     matlab.ui.control.Label
        ApparentPowerEditField          matlab.ui.control.NumericEditField
        RatingAdvantageEditFieldLabel   matlab.ui.control.Label
        RatingAdvantageEditField        matlab.ui.control.NumericEditField
        PrimaryvoltageEditFieldLabel    matlab.ui.control.Label
        PrimaryvoltageEditField         matlab.ui.control.NumericEditField
        SecondaryvoltageEditFieldLabel  matlab.ui.control.Label
        SecondaryvoltageEditField       matlab.ui.control.NumericEditField
        LoadCurrentEditFieldLabel       matlab.ui.control.Label
        LoadCurrentEditField            matlab.ui.control.NumericEditField
        SuppliedCurrentEditFieldLabel   matlab.ui.control.Label
        SuppliedCurrentEditField        matlab.ui.control.NumericEditField
        IBCEditFieldLabel               matlab.ui.control.Label
        IBCEditField                    matlab.ui.control.NumericEditField
        PowertransformedEditFieldLabel  matlab.ui.control.Label
        PowertransformedEditField       matlab.ui.control.NumericEditField
        PowerConductedEditFieldLabel    matlab.ui.control.Label
        PowerConductedEditField         matlab.ui.control.NumericEditField
        PowerlossEditFieldLabel         matlab.ui.control.Label
        PowerlossEditField              matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        VoltageregulationEditFieldLabel  matlab.ui.control.Label
        VoltageregulationEditField      matlab.ui.control.NumericEditField
        ALabel_5                        matlab.ui.control.Label
        ALabel_6                        matlab.ui.control.Label
        VLabel_12                       matlab.ui.control.Label
        VLabel_13                       matlab.ui.control.Label
        KVALabel                        matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        WLabel_3                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ALabel_7                        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ALabel_8                        matlab.ui.control.Label
        ALabel_9                        matlab.ui.control.Label
        VLabel_15                       matlab.ui.control.Label
        VLabel_16                       matlab.ui.control.Label
        KVALabel_2                      matlab.ui.control.Label
        WLabel_4                        matlab.ui.control.Label
        WLabel_5                        matlab.ui.control.Label
        WLabel_6                        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        TransformationRatioEditField_2Label  matlab.ui.control.Label
        TransformationRatioEditField_2  matlab.ui.control.NumericEditField
        EconomyinCopperEditField_2Label  matlab.ui.control.Label
        EconomyinCopperEditField_2      matlab.ui.control.NumericEditField
        ApparentPowerEditField_2Label   matlab.ui.control.Label
        ApparentPowerEditField_2        matlab.ui.control.NumericEditField
        RatingAdvantageEditField_2Label  matlab.ui.control.Label
        RatingAdvantageEditField_2      matlab.ui.control.NumericEditField
        PrimaryvoltageEditField_2Label  matlab.ui.control.Label
        PrimaryvoltageEditField_2       matlab.ui.control.NumericEditField
        SecondaryvoltageEditField_2Label  matlab.ui.control.Label
        SecondaryvoltageEditField_2     matlab.ui.control.NumericEditField
        LoadCurrentEditField_2Label     matlab.ui.control.Label
        LoadCurrentEditField_2          matlab.ui.control.NumericEditField
        SuppliedCurrentEditField_2Label  matlab.ui.control.Label
        SuppliedCurrentEditField_2      matlab.ui.control.NumericEditField
        IBCEditField_2Label             matlab.ui.control.Label
        IBCEditField_2                  matlab.ui.control.NumericEditField
        PowertransformedEditField_2Label  matlab.ui.control.Label
        PowertransformedEditField_2     matlab.ui.control.NumericEditField
        PowerConductedEditField_2Label  matlab.ui.control.Label
        PowerConductedEditField_2       matlab.ui.control.NumericEditField
        PowerlossEditField_2Label       matlab.ui.control.Label
        PowerlossEditField_2            matlab.ui.control.NumericEditField
        EfficiencyEditField_2Label      matlab.ui.control.Label
        EfficiencyEditField_2           matlab.ui.control.NumericEditField
        VoltageregulationEditField_2Label  matlab.ui.control.Label
        VoltageregulationEditField_2    matlab.ui.control.NumericEditField
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        Image3                          matlab.ui.control.Image
        EXITButton                      matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        VLabel_17                       matlab.ui.control.Label
        VLabel_18                       matlab.ui.control.Label
        VLabel_19                       matlab.ui.control.Label
        ALabel_10                       matlab.ui.control.Label
        ALabel_11                       matlab.ui.control.Label
        VLabel_20                       matlab.ui.control.Label
        Image3_2                        matlab.ui.control.Image
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        VLabel_21                       matlab.ui.control.Label
        VLabel_22                       matlab.ui.control.Label
        VLabel_23                       matlab.ui.control.Label
        ALabel_12                       matlab.ui.control.Label
        ALabel_13                       matlab.ui.control.Label
        VLabel_24                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        tapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, demo)
            app.tapp = demo;
            vs= app.tapp.Vs;
            
            if vs == app.tapp.TurnsRationEditField_3.Value
                if strcmp(app.tapp.pf_s,'Lagging')
                    isr= app.tapp.S*1000 / vs;
                    phase = acosd(app.tapp.pf);
                    Isr = isr*cosd(phase) + 1j*isr*sind(-phase);
                    NLV = vs + app.tapp.RP * Isr + 1j*app.tapp.XP*Isr ;
                    VR = (abs(NLV) - vs)*100/vs;
                    
                end
                if strcmp(app.tapp.pf_s,'Leading')
                    isr= app.tapp.S*1000 / vs;
                    phase = acosd(app.tapp.pf);
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.RP * Isr + 1j*app.tapp.XP * Isr ;
                    VR = (abs(NLV) - vs)*100/vs;
                end
                if strcmp(app.tapp.pf_s,'Unity')
                    isr= app.tapp.S*1000 / vs;
                    phase = 0;
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.RP * Isr + 1j*app.tapp.XP*Isr ;
                    VR = (abs(NLV) - vs)*100/vs;
                end
                Copper_loss = isr^2 * app.tapp.RP;
                Core_loss= (abs(NLV))^2 / app.tapp.RC;
                Output_power= vs*isr*cosd(phase);
                Efficiency= vs*isr*cosd(phase) * 100 / (isr^2 * app.tapp.RP + (abs(NLV))^2 / app.tapp.RC + vs*isr*cosd(phase));
            end
            
            if vs ~= app.tapp.TurnsRationEditField_3.Value
                if strcmp(app.tapp.pf_s,'Lagging')
                    isr= app.tapp.S*1000 / vs;
                    phase = acosd(app.tapp.pf);
                    Isr = isr*cosd(phase) + 1j*isr*sind(-phase);
                    NLV = vs + app.tapp.r_s * Isr + 1j*app.tapp.x_s*Isr ;
                    VR = (abs(NLV) - vs)*100/vs;
                end
                if strcmp(app.tapp.pf_s,'Leading')
                    isr= app.tapp.S*1000 / vs;
                    phase = acosd(app.tapp.pf);
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.r_s * Isr + 1j*app.tapp.x_s*Isr ;
                    VR = (abs(NLV) - vs)*100/vs;
                end
                if strcmp(app.tapp.pf_s,'Unity')
                    isr= app.tapp.S*1000 / vs;
                    phase = 0;
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.r_s * Isr + 1j*app.tapp.x_s*Isr ;
                    VR = (abs(NLV) - vs)*100/vs;
                end
                
                Copper_loss = isr^2 * app.tapp.RP;
                Core_loss= (abs(NLV))^2 / app.tapp.RC;
                Output_power= vs*isr*cosd(phase);
                Efficiency= vs*isr*cosd(phase) * 100 / (isr^2 * app.tapp.RP + (abs(NLV))^2 / app.tapp.RC + vs*isr*cosd(phase));
            end
            
            s= app.tapp.S*1000;
            V_P= app.tapp.TurnsRationEditField_4.Value;
            V_S= app.tapp.TurnsRationEditField_3.Value;
            pf=  app.tapp.pf;
            n=   Efficiency;
            n=n/100;
            volt_regulation= VR;


            k1=(V_P+V_S)/V_P;
            fprintf("Transformation Ratio:%.2f\n",k1);
            
            e_copper=k1*100;     
            S_auto=((V_S+V_P)/V_S)*s;%apparent power of autotransformer
            Rating_advantage=S_auto/s;           
            V1=V_P;          
            V2=V_P+V_S;           
            Is=(s/V_P)/(k1-1);%secondary current            
            Ip=k1*Is;%primary current            
            Ibc=Ip-Is;            
            trans_power=V_S*Is;            
            conduct_power=S_auto-trans_power;            
            power_loss=((1/n)-1)*s*pf;            
            efficiency=(S_auto/(S_auto+power_loss))*100;            
            regulation=(1-(1/k1))*volt_regulation;

            app.TransformationRatioEditField.Value = k1;
            app.EconomyinCopperEditField.Value = e_copper;
            app.ApparentPowerEditField.Value = S_auto/1000;
            app.RatingAdvantageEditField.Value = Rating_advantage;
            app.PrimaryvoltageEditField.Value = V1;
            app.SecondaryvoltageEditField.Value = V2;
            app.LoadCurrentEditField.Value = Is;
            app.SuppliedCurrentEditField.Value = Ip;
            app.IBCEditField.Value = Ibc;
            app.PowertransformedEditField.Value = trans_power;
            app.PowerConductedEditField.Value = conduct_power;
            app.PowerlossEditField.Value = power_loss;
            app.EfficiencyEditField.Value = efficiency;
            app.VoltageregulationEditField.Value = regulation;
            
            app.EditField.Value = Is;
            app.EditField_4.Value = V_S;
            app.EditField_5.Value = Ip;
            app.EditField_6.Value = V_P;
            app.EditField_7.Value = Ibc;
            app.EditField_2.Value = V2;
            app.EditField_3.Value = V_P;


            %configuration 2
            
                
            k2=(V_P+V_S)/V_S;            
            e_copper2=k2*100;            
            S_auto2=((V_S+V_P)/V_P)*s;%apparent power of autotransformer           
            Rating_advantage2=S_auto2/s;            
            V1=V_S;            
            V2=V_P+V_S;           
            Is2=(s/V_S)/(k2-1);%secondary current            
            Ip2=k2*Is2;%primary current            
            Ibc2=Ip2-Is2;            
            trans_power2=V_P*Is2;            
            conduct_power2=S_auto2-trans_power2;           
            power_loss2=((1/n)-1)*s*pf;           
            efficiency2=(S_auto2/(S_auto2+power_loss2))*100;          
            regulation2=(1-(1/k2))*volt_regulation;
            
            app.TransformationRatioEditField_2.Value = k2;
            app.EconomyinCopperEditField_2.Value = e_copper2;
            app.ApparentPowerEditField_2.Value = S_auto2/1000;
            app.RatingAdvantageEditField_2.Value = Rating_advantage2;
            app.PrimaryvoltageEditField_2.Value = V1;
            app.SecondaryvoltageEditField_2.Value = V2;
            app.LoadCurrentEditField_2.Value = Is2;
            app.SuppliedCurrentEditField_2.Value = Ip2;
            app.IBCEditField_2.Value = Ibc2;
            app.PowertransformedEditField_2.Value = trans_power2;
            app.PowerConductedEditField_2.Value = conduct_power2;
            app.PowerlossEditField_2.Value = power_loss2;
            app.EfficiencyEditField_2.Value = efficiency2;
            app.VoltageregulationEditField_2.Value = regulation2;
            
            app.EditField_8.Value = Is2;
            app.EditField_9.Value = V2;
            app.EditField_12.Value = Ip2;
            app.EditField_11.Value = V_P;
            app.EditField_14.Value = Ibc2;
            app.EditField_10.Value = V_S;
            app.EditField_13.Value = V_S;
         

        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Equivalent Step-Up Auto Transformer'};
            app.ALabel_3 = uilabel(app.UIFigure);
            app.ALabel_3.Text = 'A';
            app.TransformationRatioEditFieldLabel = uilabel(app.UIFigure);
            app.TransformationRatioEditFieldLabel.Text = 'Transformation Ratio';
            app.TransformationRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.EconomyinCopperEditFieldLabel = uilabel(app.UIFigure);
            app.EconomyinCopperEditFieldLabel.Text = 'Economy in Copper';
            app.EconomyinCopperEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.ApparentPowerEditFieldLabel = uilabel(app.UIFigure);
            app.ApparentPowerEditFieldLabel.Text = 'Apparent Power';
            app.ApparentPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.RatingAdvantageEditFieldLabel = uilabel(app.UIFigure);
            app.RatingAdvantageEditFieldLabel.Text = 'Rating Advantage';
            app.RatingAdvantageEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryvoltageEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryvoltageEditFieldLabel.Text = 'Primary voltage';
            app.PrimaryvoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryvoltageEditFieldLabel = uilabel(app.UIFigure);
            app.SecondaryvoltageEditFieldLabel.Text = 'Secondary voltage';
            app.SecondaryvoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.LoadCurrentEditFieldLabel.Text = 'Load Current';
            app.LoadCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuppliedCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.SuppliedCurrentEditFieldLabel.Text = 'Supplied Current';
            app.SuppliedCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.IBCEditFieldLabel = uilabel(app.UIFigure);
            app.IBCEditFieldLabel.Text = 'IBC';
            app.IBCEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowertransformedEditFieldLabel = uilabel(app.UIFigure);
            app.PowertransformedEditFieldLabel.Text = 'Power transformed';
            app.PowertransformedEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerConductedEditFieldLabel = uilabel(app.UIFigure);
            app.PowerConductedEditFieldLabel.Text = 'Power Conducted';
            app.PowerConductedEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerlossEditFieldLabel = uilabel(app.UIFigure);
            app.PowerlossEditFieldLabel.Text = 'Power loss';
            app.PowerlossEditField = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageregulationEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageregulationEditFieldLabel.Text = 'Voltage regulation';
            app.VoltageregulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ALabel_5 = uilabel(app.UIFigure);
            app.ALabel_5.Text = 'A';
            app.ALabel_6 = uilabel(app.UIFigure);
            app.ALabel_6.Text = 'A';
            app.VLabel_12 = uilabel(app.UIFigure);
            app.VLabel_12.Text = 'V';
            app.VLabel_13 = uilabel(app.UIFigure);
            app.VLabel_13.Text = 'V';
            app.KVALabel = uilabel(app.UIFigure);
            app.KVALabel.Text = 'KVA';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.WLabel_2 = uilabel(app.UIFigure);
            app.WLabel_2.Text = 'W';
            app.WLabel_3 = uilabel(app.UIFigure);
            app.WLabel_3.Text = 'W';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '%';
            app.ALabel_7 = uilabel(app.UIFigure);
            app.ALabel_7.Text = 'A';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '%';
            app.ALabel_8 = uilabel(app.UIFigure);
            app.ALabel_8.Text = 'A';
            app.ALabel_9 = uilabel(app.UIFigure);
            app.ALabel_9.Text = 'A';
            app.VLabel_15 = uilabel(app.UIFigure);
            app.VLabel_15.Text = 'V';
            app.VLabel_16 = uilabel(app.UIFigure);
            app.VLabel_16.Text = 'V';
            app.KVALabel_2 = uilabel(app.UIFigure);
            app.KVALabel_2.Text = 'KVA';
            app.WLabel_4 = uilabel(app.UIFigure);
            app.WLabel_4.Text = 'W';
            app.WLabel_5 = uilabel(app.UIFigure);
            app.WLabel_5.Text = 'W';
            app.WLabel_6 = uilabel(app.UIFigure);
            app.WLabel_6.Text = 'W';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '%';
            app.TransformationRatioEditField_2Label = uilabel(app.UIFigure);
            app.TransformationRatioEditField_2Label.Text = 'Transformation Ratio';
            app.TransformationRatioEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EconomyinCopperEditField_2Label = uilabel(app.UIFigure);
            app.EconomyinCopperEditField_2Label.Text = 'Economy in Copper';
            app.EconomyinCopperEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ApparentPowerEditField_2Label = uilabel(app.UIFigure);
            app.ApparentPowerEditField_2Label.Text = 'Apparent Power';
            app.ApparentPowerEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RatingAdvantageEditField_2Label = uilabel(app.UIFigure);
            app.RatingAdvantageEditField_2Label.Text = 'Rating Advantage';
            app.RatingAdvantageEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryvoltageEditField_2Label = uilabel(app.UIFigure);
            app.PrimaryvoltageEditField_2Label.Text = 'Primary voltage';
            app.PrimaryvoltageEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryvoltageEditField_2Label = uilabel(app.UIFigure);
            app.SecondaryvoltageEditField_2Label.Text = 'Secondary voltage';
            app.SecondaryvoltageEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LoadCurrentEditField_2Label = uilabel(app.UIFigure);
            app.LoadCurrentEditField_2Label.Text = 'Load Current';
            app.LoadCurrentEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SuppliedCurrentEditField_2Label = uilabel(app.UIFigure);
            app.SuppliedCurrentEditField_2Label.Text = 'Supplied Current';
            app.SuppliedCurrentEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.IBCEditField_2Label = uilabel(app.UIFigure);
            app.IBCEditField_2Label.Text = 'IBC';
            app.IBCEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PowertransformedEditField_2Label = uilabel(app.UIFigure);
            app.PowertransformedEditField_2Label.Text = 'Power transformed';
            app.PowertransformedEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PowerConductedEditField_2Label = uilabel(app.UIFigure);
            app.PowerConductedEditField_2Label.Text = 'Power Conducted';
            app.PowerConductedEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PowerlossEditField_2Label = uilabel(app.UIFigure);
            app.PowerlossEditField_2Label.Text = 'Power loss';
            app.PowerlossEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyEditField_2Label = uilabel(app.UIFigure);
            app.EfficiencyEditField_2Label.Text = 'Efficiency';
            app.EfficiencyEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageregulationEditField_2Label = uilabel(app.UIFigure);
            app.VoltageregulationEditField_2Label.Text = 'Voltage regulation';
            app.VoltageregulationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Configuration -01'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Configuration -02'};
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'suc1.png';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.VLabel_17 = uilabel(app.UIFigure);
            app.VLabel_17.Text = 'V';
            app.VLabel_18 = uilabel(app.UIFigure);
            app.VLabel_18.Text = 'V';
            app.VLabel_19 = uilabel(app.UIFigure);
            app.VLabel_19.Text = 'V';
            app.ALabel_10 = uilabel(app.UIFigure);
            app.ALabel_10.Text = 'A';
            app.ALabel_11 = uilabel(app.UIFigure);
            app.ALabel_11.Text = 'A';
            app.VLabel_20 = uilabel(app.UIFigure);
            app.VLabel_20.Text = 'V';
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = 'suc1.png';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.VLabel_21 = uilabel(app.UIFigure);
            app.VLabel_21.Text = 'V';
            app.VLabel_22 = uilabel(app.UIFigure);
            app.VLabel_22.Text = 'V';
            app.VLabel_23 = uilabel(app.UIFigure);
            app.VLabel_23.Text = 'V';
            app.ALabel_12 = uilabel(app.UIFigure);
            app.ALabel_12.Text = 'A';
            app.ALabel_13 = uilabel(app.UIFigure);
            app.ALabel_13.Text = 'A';
            app.VLabel_24 = uilabel(app.UIFigure);
            app.VLabel_24.Text = 'V';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AT_OSCU(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end