classdef AUTOTR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TurnsRationEditField            matlab.ui.control.NumericEditField
        KVALabel                        matlab.ui.control.Label
        SecondaryVoltsLabel             matlab.ui.control.Label
        PrimaryVoltsLabel               matlab.ui.control.Label
        TurnsRationEditFieldLabel       matlab.ui.control.Label
        RatedPowerinKVASEditFieldLabel  matlab.ui.control.Label
        RatedPowerinKVASEditField       matlab.ui.control.NumericEditField
        TurnsRationEditField_2Label     matlab.ui.control.Label
        TurnsRationEditField_2          matlab.ui.control.NumericEditField
        PFEditFieldLabel                matlab.ui.control.Label
        PFEditField                     matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        VoltageRegulationEditFieldLabel  matlab.ui.control.Label
        VoltageRegulationEditField      matlab.ui.control.NumericEditField
        ConverttoDropDown               matlab.ui.control.DropDown
        ConverttoButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TurnsRationEditField_3          matlab.ui.control.NumericEditField
        SecondaryVoltsLabel_2           matlab.ui.control.Label
        PrimaryVoltsLabel_2             matlab.ui.control.Label
        TurnsRationEditFieldLabel_2     matlab.ui.control.Label
        TurnsRationEditField_4          matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        jLabel                          matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        ObtainButton                    matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        SupplyCurrentEditFieldLabel     matlab.ui.control.Label
        SupplyCurrentEditField          matlab.ui.control.NumericEditField
        PhasorDiagramButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        S;
        pf;
        VP;
        VS;
        VR;
        EFF;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConverttoButton
        function ConverttoButtonPushed(app, event)
            app.S = app.RatedPowerinKVASEditField.Value;
            app.pf = app.PFEditField.Value;
            app.VP = app.TurnsRationEditField_2.Value;
            app.VS = app.TurnsRationEditField.Value;
            app.VR = app.VoltageRegulationEditField.Value;
            app.EFF = app.EfficiencyEditField.Value;  
            
            
            if strcmp(app.ConverttoDropDown.Value,'Step-up Auto Transformer')
                AUTOTRU(app);
            end
            if strcmp(app.ConverttoDropDown.Value,'Step-down Auto Transformer')
                AUTOTRD(app);
            end
        end

        % Button pushed function: ObtainButton
        function ObtainButtonPushed(app, event)
            vp = app.TurnsRationEditField_4.Value;
            vs = app.TurnsRationEditField_3.Value;
            r = app.rEditField.Value;
            x = app.xEditField.Value;
            i_s = app.SupplyCurrentEditField.Value;
            v2 = vs;
            k = vs/vp;
            ur = r + 1j*x ;
            theta = atand(x/r);
            i1 = i_s*(cosd(theta) + 1j*sind(theta)) ;
            rp = vp / i1;
            n1_2 = rp/ur ;
            
            n2 = n1_2/(1/k -1);
            rs = ur*n2;
            
            zep = rp + rs*(1/k -1)^2 ;
            
            app.EditField.Value = real(zep);
            app.EditField_2.Value = abs(imag(zep));
            
            v1 = v2/k + i1*zep;
            
            app.EditField_3.Value = abs(v1);
            app.EditField_4.Value = v2/k;


        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: PhasorDiagramButton
        function PhasorDiagramButtonPushed(app, event)
            vp = app.TurnsRationEditField_4.Value;
            vs = app.TurnsRationEditField_3.Value;
            r = app.rEditField.Value;
            x = app.xEditField.Value;
            i_s = app.SupplyCurrentEditField.Value;
            v2 = vs;
            k = vs/vp;
            ur = r + 1j*x ;
            theta = atand(x/r);
            i1 = i_s*(cosd(theta) + 1j*sind(theta)) ;
            rp = vp / i1;
            n1_2 = rp/ur ;
            
            n2 = n1_2/(1/k -1);
            rs = ur*n2;
            
            zep = rp + rs*(1/k -1)^2 ;


            v1 = v2/k + i1*zep;
            
      
            v2 = v2/k;
            v_r = i1*real(zep) ;
            v_x = 1j*i1*imag(zep) ;
            
            quiver(0,0,real(v1),imag(v1),1,'r','MaxHeadSize',0.1,'Linewidth',2);
            hold on;
            quiver(0,0,real(i1),imag(i1),10,'k','MaxHeadSize',1,'Linewidth',2);
            quiver(0,0,real(v2),imag(v2),1,'b','MaxHeadSize',0.1,'Linewidth',2);
            quiver(real(v2),imag(v2),real(v_r),imag(v_r),1,'g','MaxHeadSize',0.1,'Linewidth',2);
            quiver(real(v2+v_r),imag(v2+v_r),real(v_x),imag(v_x),1,'c','MaxHeadSize',0.1,'Linewidth',2);
            hold off;
            legend('V1','I1','V2/k','I1*R','I1*X');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'CONVERSION'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Two Winding Transformer Parameters'};
            app.TurnsRationEditField = uieditfield(app.UIFigure, 'numeric');
            app.KVALabel = uilabel(app.UIFigure);
            app.KVALabel.Text = 'KVA';
            app.SecondaryVoltsLabel = uilabel(app.UIFigure);
            app.SecondaryVoltsLabel.Text = 'Secondary Volts';
            app.PrimaryVoltsLabel = uilabel(app.UIFigure);
            app.PrimaryVoltsLabel.Text = 'Primary Volts';
            app.TurnsRationEditFieldLabel = uilabel(app.UIFigure);
            app.TurnsRationEditFieldLabel.Text = ':';
            app.RatedPowerinKVASEditFieldLabel = uilabel(app.UIFigure);
            app.RatedPowerinKVASEditFieldLabel.Text = 'Rated Power in KVA. S';
            app.RatedPowerinKVASEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurnsRationEditField_2Label = uilabel(app.UIFigure);
            app.TurnsRationEditField_2Label.Text = 'Turns Ration';
            app.TurnsRationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PFEditFieldLabel = uilabel(app.UIFigure);
            app.PFEditFieldLabel.Text = 'PF';
            app.PFEditField = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageRegulationEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageRegulationEditFieldLabel.Text = 'Voltage Regulation';
            app.VoltageRegulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConverttoDropDown = uidropdown(app.UIFigure);
            app.ConverttoDropDown.Items = {'Step-up Auto Transformer', 'Step-down Auto Transformer'};
            app.ConverttoDropDown.Value = 'Step-up Auto Transformer';
            app.ConverttoButton = uibutton(app.UIFigure, 'push');
            app.ConverttoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoButtonPushed, true);
            app.ConverttoButton.Text = 'Convert to';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Equivalent Circuit'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Auto Transformer Parameters'};
            app.TurnsRationEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryVoltsLabel_2 = uilabel(app.UIFigure);
            app.SecondaryVoltsLabel_2.Text = 'Secondary Volts';
            app.PrimaryVoltsLabel_2 = uilabel(app.UIFigure);
            app.PrimaryVoltsLabel_2.Text = 'Primary Volts';
            app.TurnsRationEditFieldLabel_2 = uilabel(app.UIFigure);
            app.TurnsRationEditFieldLabel_2.Text = ':';
            app.TurnsRationEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.jLabel = uilabel(app.UIFigure);
            app.jLabel.Text = 'j';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Impedance  per turn'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ATEQ.png';
            app.ObtainButton = uibutton(app.UIFigure, 'push');
            app.ObtainButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainButtonPushed, true);
            app.ObtainButton.Text = 'Obtain';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.SupplyCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.SupplyCurrentEditFieldLabel.Text = 'Supply Current';
            app.SupplyCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhasorDiagramButton = uibutton(app.UIFigure, 'push');
            app.PhasorDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @PhasorDiagramButtonPushed, true);
            app.PhasorDiagramButton.Text = 'Phasor Diagram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUTOTR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end