classdef TransformerPerformance2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VoltageRegulationVREditFieldLabel  matlab.ui.control.Label
        VoltageRegulationVREditField  matlab.ui.control.NumericEditField
        FullloadcurrentILEditFieldLabel  matlab.ui.control.Label
        FullloadcurrentILEditField    matlab.ui.control.NumericEditField
        NoloadvoltageEditFieldLabel   matlab.ui.control.Label
        NoloadvoltageEditField        matlab.ui.control.NumericEditField
        FullloadvoltageVLEditFieldLabel  matlab.ui.control.Label
        FullloadvoltageVLEditField    matlab.ui.control.NumericEditField
        IL_PhaseEditFieldLabel        matlab.ui.control.Label
        IL_PhaseEditField             matlab.ui.control.NumericEditField
        VL_PhaseEditFieldLabel        matlab.ui.control.Label
        VL_PhaseEditField             matlab.ui.control.NumericEditField
        PhaseEditField_2Label         matlab.ui.control.Label
        PhaseEditField_2              matlab.ui.control.NumericEditField
        TextArea                      matlab.ui.control.TextArea
        CopperLossEditFieldLabel      matlab.ui.control.Label
        CopperLossEditField           matlab.ui.control.NumericEditField
        CoreLossEditFieldLabel        matlab.ui.control.Label
        CoreLossEditField             matlab.ui.control.NumericEditField
        OutputPowerEditFieldLabel     matlab.ui.control.Label
        OutputPowerEditField          matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel      matlab.ui.control.Label
        EfficiencyEditField           matlab.ui.control.NumericEditField
        EXITButton                    matlab.ui.control.Button
        PhaseEditField_2Label_2       matlab.ui.control.Label
        VLabel                        matlab.ui.control.Label
        VLabel_2                      matlab.ui.control.Label
        ALabel                        matlab.ui.control.Label
        WLabel                        matlab.ui.control.Label
        WLabel_2                      matlab.ui.control.Label
        WLabel_3                      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        degreeLabel                   matlab.ui.control.Label
        degreeLabel_2                 matlab.ui.control.Label
        degreeLabel_3                 matlab.ui.control.Label
        ObserveVRVariationButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        tapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, demo)
            app.tapp = demo;
            vs= app.tapp.Vs;
            app.FullloadvoltageVLEditField.Value = vs;
            app.VL_PhaseEditField.Value = 0 ;
            
            if vs == app.tapp.TurnsRationEditField_2.Value
                if strcmp(app.tapp.pf_s,'Lagging')
                    isr= app.tapp.S*1000 / vs;
                    app.FullloadcurrentILEditField.Value = isr;
                    phase = acosd(app.tapp.pf);
                    app.IL_PhaseEditField.Value = -phase;
                    Isr = isr*cosd(phase) + 1j*isr*sind(-phase);
                    NLV = vs + app.tapp.RP * Isr + 1j*app.tapp.XP*Isr ;
                    app.NoloadvoltageEditField.Value = abs(NLV);
                    app.PhaseEditField_2.Value = angle(NLV)*180/pi ;
                    app.VoltageRegulationVREditField.Value = (abs(NLV) - vs)*100/vs;
                end
                if strcmp(app.tapp.pf_s,'Leading')
                    isr= app.tapp.S*1000 / vs;
                    app.FullloadcurrentILEditField.Value = isr;
                    phase = acosd(app.tapp.pf);
                    app.IL_PhaseEditField.Value = phase;
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.RP * Isr + 1j*app.tapp.XP * Isr ;
                    app.NoloadvoltageEditField.Value = abs(NLV);
                    app.PhaseEditField_2.Value = angle(NLV)*180/pi ;
                    app.VoltageRegulationVREditField.Value = (abs(NLV) - vs)*100/vs;
                end
                if strcmp(app.tapp.pf_s,'Unity')
                    isr= app.tapp.S*1000 / vs;
                    app.FullloadcurrentILEditField.Value = isr;
                    phase = 0;
                    app.IL_PhaseEditField.Value = phase;
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.RP * Isr + 1j*app.tapp.XP*Isr ;
                    app.NoloadvoltageEditField.Value = abs(NLV);
                    app.PhaseEditField_2.Value = angle(NLV)*180/pi ;
                    app.VoltageRegulationVREditField.Value = (abs(NLV) - vs)*100/vs;
                end
                app.CopperLossEditField.Value = isr^2 * app.tapp.RP;
                app.CoreLossEditField.Value = (abs(NLV))^2 / app.tapp.RC;
                app.OutputPowerEditField.Value = vs*isr*cosd(phase);
                app.EfficiencyEditField.Value = vs*isr*cosd(phase) * 100 / (isr^2 * app.tapp.RP + (abs(NLV))^2 / app.tapp.RC + vs*isr*cosd(phase));
            end
            
            if vs ~= app.tapp.TurnsRationEditField_2.Value
                if strcmp(app.tapp.pf_s,'Lagging')
                    isr= app.tapp.S*1000 / vs;
                    app.FullloadcurrentILEditField.Value = isr;
                    phase = acosd(app.tapp.pf);
                    app.IL_PhaseEditField.Value = -phase;
                    Isr = isr*cosd(phase) + 1j*isr*sind(-phase);
                    NLV = vs + app.tapp.r_s * Isr + 1j*app.tapp.x_s*Isr ;
                    app.NoloadvoltageEditField.Value = abs(NLV);
                    app.PhaseEditField_2.Value = angle(NLV)*180/pi ;
                    app.VoltageRegulationVREditField.Value = (abs(NLV) - vs)*100/vs;
                end
                if strcmp(app.tapp.pf_s,'Leading')
                    isr= app.tapp.S*1000 / vs;
                    app.FullloadcurrentILEditField.Value = isr;
                    phase = acosd(app.tapp.pf);
                    app.IL_PhaseEditField.Value = phase;
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.r_s * Isr + 1j*app.tapp.x_s*Isr ;
                    app.NoloadvoltageEditField.Value = abs(NLV);
                    app.PhaseEditField_2.Value = angle(NLV)*180/pi ;
                    app.VoltageRegulationVREditField.Value = (abs(NLV) - vs)*100/vs;
                end
                if strcmp(app.tapp.pf_s,'Unity')
                    isr= app.tapp.S*1000 / vs;
                    app.FullloadcurrentILEditField.Value = isr;
                    phase = 0;
                    app.IL_PhaseEditField.Value = phase;
                    Isr = isr*cosd(phase) + 1j*isr*sind(phase);
                    NLV = vs + app.tapp.r_s * Isr + 1j*app.tapp.x_s*Isr ;
                    app.NoloadvoltageEditField.Value = abs(NLV);
                    app.PhaseEditField_2.Value = angle(NLV)*180/pi ;
                    app.VoltageRegulationVREditField.Value = (abs(NLV) - vs)*100/vs;
                end
                
                app.CopperLossEditField.Value = isr^2 * app.tapp.r_s;
                app.CoreLossEditField.Value = (abs(NLV))^2 / app.tapp.r_c;
                app.OutputPowerEditField.Value = vs*isr*cosd(phase);
                app.EfficiencyEditField.Value = vs*isr*cosd(phase) * 100 / (isr^2 * app.tapp.r_s + (abs(NLV))^2 / app.tapp.r_c + vs*isr*cosd(phase));
            end
            
            
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ObserveVRVariationButton
        function ObserveVRVariationButtonPushed(app, event)
                      
            V_S = app.tapp.TurnsRationEditField.Value;
            I_S= app.tapp.S*1000 /V_S;
            amps = 0:(I_S/10):I_S;
            % I_S = 65.2;
            % R_S = V_S/I_S;
            % r_s = V_S./amps;
            % r_percent = r_s*100/R_S;
            Req = app.tapp.r_s;
            Xeq = app.tapp.x_s;
            Pf = app.tapp.pf;
            ps = sind(acosd(Pf));
            
            I(1,:) = amps .* (Pf - 1j*ps);
            I(2,:) = amps .* 1;
            I(3,:) = amps .* (Pf + 1j*ps);
            
            VPa = V_S + Req.*I + 1j.*Xeq.*I;
            VR = (abs(VPa)-V_S)./V_S.*100;
            
            plot(amps,VR(1,:),'b-','LineWidth',2.0);
            hold on;
            plot(amps,VR(2,:),'k--','LineWidth',2.0);
            plot(amps,VR(3,:),'r-.','LineWidth',2.0);
            title ('\bfVoltage Regulation Versus Load');
            xlabel ('\bfLoad (A)');
            ylabel ('\bfVoltage Regulation (%)');
            legend([num2str(Pf),' PF lagging'],'Unity PF',[num2str(Pf),' PF leading']);
            hold off
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VoltageRegulationVREditFieldLabel = uilabel(app.UIFigure);
            app.VoltageRegulationVREditFieldLabel.Text = 'Voltage Regulation (VR)';
            app.VoltageRegulationVREditField = uieditfield(app.UIFigure, 'numeric');
            app.FullloadcurrentILEditFieldLabel = uilabel(app.UIFigure);
            app.FullloadcurrentILEditFieldLabel.Text = 'Full load current(IL)';
            app.FullloadcurrentILEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoloadvoltageEditFieldLabel = uilabel(app.UIFigure);
            app.NoloadvoltageEditFieldLabel.Text = 'No load voltage';
            app.NoloadvoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.FullloadvoltageVLEditFieldLabel = uilabel(app.UIFigure);
            app.FullloadvoltageVLEditFieldLabel.Text = 'Full load voltage(VL)';
            app.FullloadvoltageVLEditField = uieditfield(app.UIFigure, 'numeric');
            app.IL_PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.IL_PhaseEditFieldLabel.Text = 'IL_Phase';
            app.IL_PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.VL_PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.VL_PhaseEditFieldLabel.Text = 'VL_Phase';
            app.VL_PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditField_2Label = uilabel(app.UIFigure);
            app.PhaseEditField_2Label.Text = 'Phase';
            app.PhaseEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'TRANSFORMER PERFORMANCE'};
            app.CopperLossEditFieldLabel = uilabel(app.UIFigure);
            app.CopperLossEditFieldLabel.Text = 'Copper Loss';
            app.CopperLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoreLossEditFieldLabel = uilabel(app.UIFigure);
            app.CoreLossEditFieldLabel.Text = 'Core Loss';
            app.CoreLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputPowerEditFieldLabel = uilabel(app.UIFigure);
            app.OutputPowerEditFieldLabel.Text = 'Output Power';
            app.OutputPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.PhaseEditField_2Label_2 = uilabel(app.UIFigure);
            app.PhaseEditField_2Label_2.Text = '%';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.WLabel_2 = uilabel(app.UIFigure);
            app.WLabel_2.Text = 'W';
            app.WLabel_3 = uilabel(app.UIFigure);
            app.WLabel_3.Text = 'W';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.degreeLabel = uilabel(app.UIFigure);
            app.degreeLabel.Text = 'degree';
            app.degreeLabel_2 = uilabel(app.UIFigure);
            app.degreeLabel_2.Text = 'degree';
            app.degreeLabel_3 = uilabel(app.UIFigure);
            app.degreeLabel_3.Text = 'degree';
            app.ObserveVRVariationButton = uibutton(app.UIFigure, 'push');
            app.ObserveVRVariationButton.ButtonPushedFcn = createCallbackFcn(app, @ObserveVRVariationButtonPushed, true);
            app.ObserveVRVariationButton.Text = 'Observe VR Variation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransformerPerformance2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end