classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        AddButton  matlab.ui.control.Button
        UIAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CommMediator AppMediator % Description
        X % Description
        Y % Description
    end
    
    methods (Access = private)
        
        function onAddedItem(app, x, y)
            app.X = [app.X, x];
            app.Y = [app.Y, y];
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, app.X, app.Y, 'go');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mediator)
            app.CommMediator = mediator;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            callback = @app.onAddedItem;
            app.CommMediator.showValueDialogue(callback);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end