classdef EQCP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TurnsRationEditField            matlab.ui.control.NumericEditField
        KVALabel                        matlab.ui.control.Label
        SecondaryVoltsLabel             matlab.ui.control.Label
        PrimaryVoltsLabel               matlab.ui.control.Label
        TurnsRationEditFieldLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ObtainEquivalentCircuitButton   matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        RatedPowerinKVASEditFieldLabel  matlab.ui.control.Label
        RatedPowerinKVASEditField       matlab.ui.control.NumericEditField
        TurnsRationEditField_2Label     matlab.ui.control.Label
        TurnsRationEditField_2          matlab.ui.control.NumericEditField
        TextArea_2                      matlab.ui.control.TextArea
        R_eqohmEditFieldLabel           matlab.ui.control.Label
        R_eqohmEditField                matlab.ui.control.NumericEditField
        X_eqohmEditFieldLabel           matlab.ui.control.Label
        X_eqohmEditField                matlab.ui.control.NumericEditField
        TextArea_3                      matlab.ui.control.TextArea
        SideDropDown                    matlab.ui.control.DropDown
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_7                      matlab.ui.control.TextArea
        SideDropDown_2                  matlab.ui.control.DropDown
        R_cohmLabel                     matlab.ui.control.Label
        R_cohmEditField                 matlab.ui.control.NumericEditField
        X_cohmEditFieldLabel            matlab.ui.control.Label
        X_cohmEditField                 matlab.ui.control.NumericEditField
        TextArea_8                      matlab.ui.control.TextArea
        TextArea_9                      matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        R_C2EditFieldLabel              matlab.ui.control.Label
        R_C2EditFieldLabel_2            matlab.ui.control.Label
        TransformerPerformanceButton    matlab.ui.control.Button
        R_e1EditFieldLabel              matlab.ui.control.Label
        R_e1EditFieldLabel_2            matlab.ui.control.Label
        R_e1EditField                   matlab.ui.control.NumericEditField
        R_e1EditFieldLabel_3            matlab.ui.control.Label
        jEditFieldLabel                 matlab.ui.control.Label
        jEditField                      matlab.ui.control.NumericEditField
        j_Xm1EditFieldLabel             matlab.ui.control.Label
        j_Xm1EditField                  matlab.ui.control.NumericEditField
        R_C1EditFieldLabel              matlab.ui.control.Label
        R_C1EditField                   matlab.ui.control.NumericEditField
        R_e1EditField_2Label            matlab.ui.control.Label
        R_e1EditField_2                 matlab.ui.control.NumericEditField
        j_Xm2EditFieldLabel             matlab.ui.control.Label
        j_Xm2EditField                  matlab.ui.control.NumericEditField
        R_C2EditFieldLabel_3            matlab.ui.control.Label
        R_C2EditField                   matlab.ui.control.NumericEditField
        LoadPFEditFieldLabel            matlab.ui.control.Label
        LoadPFEditField                 matlab.ui.control.NumericEditField
        PFDropDownLabel                 matlab.ui.control.Label
        PFDropDown                      matlab.ui.control.DropDown
        jEditField_2Label               matlab.ui.control.Label
        jEditField_2                    matlab.ui.control.NumericEditField
        LoadVoltageEditFieldLabel       matlab.ui.control.Label
        LoadVoltageEditField            matlab.ui.control.NumericEditField
        ConverttoDropDown               matlab.ui.control.DropDown
        ConverttoButton                 matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        S;
        pf;
        pf_s;
        Vs;
        RC;
        XM;
        RP;
        XP;
        r_c;
        x_m;
        r_s;
        x_s;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: ObtainEquivalentCircuitButton
        function ObtainEquivalentCircuitButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField.Value;
            RHV = app.TurnsRationEditField_2.Value;
            LHV = app.TurnsRationEditField.Value;
            Side1 = app.SideDropDown.Value;
            Side2 = app.SideDropDown_2.Value;
            
            a = RHV/LHV; % Turns ratio
            
            req = app.R_eqohmEditField.Value;
            xeq = app.X_eqohmEditField.Value;
            
            rc = app.R_cohmEditField.Value;
            xm = app.X_cohmEditField.Value;
            
             if strcmp(Side1,'High Voltage Side') && strcmp(Side2,'Low Voltage Side')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C1EditField.Value = a^2 * rc ;
                app.j_Xm1EditField.Value = a^2 * xm;
                app.R_e1EditField.Value = req/a^2;
                app.jEditField.Value = xeq/a^2;
                
            end
            if strcmp(Side1,'Low Voltage Side') && strcmp(Side2,'High Voltage Side')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField_2.Value = req*a^2;
                app.jEditField_2.Value = xeq*a^2;
                
            end
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField.Value;
            app.XP = app.jEditField.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField_2.Value;
            app.x_s = app.jEditField_2.Value;
        end

        % Button pushed function: TransformerPerformanceButton
        function TransformerPerformanceButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField.Value;
            RHV = app.TurnsRationEditField_2.Value;
            LHV = app.TurnsRationEditField.Value;
            Side1 = app.SideDropDown.Value;
            Side2 = app.SideDropDown_2.Value;
            
            a = RHV/LHV; % Turns ratio
            
            req = app.R_eqohmEditField.Value;
            xeq = app.X_eqohmEditField.Value;
            
            rc = app.R_cohmEditField.Value;
            xm = app.X_cohmEditField.Value;
            
             if strcmp(Side1,'High Voltage Side') && strcmp(Side2,'Low Voltage Side')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C1EditField.Value = a^2 * rc ;
                app.j_Xm1EditField.Value = a^2 * xm;
                app.R_e1EditField.Value = req/a^2;
                app.jEditField.Value = xeq/a^2;
                
            end
            if strcmp(Side1,'Low Voltage Side') && strcmp(Side2,'High Voltage Side')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField_2.Value = req*a^2;
                app.jEditField_2.Value = xeq*a^2;
                
            end
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField_2.Value;
            app.XP = app.jEditField_2.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField.Value;
            app.x_s = app.jEditField.Value;
            
            TransformerPerformance2(app);
            
                        
        end

        % Button pushed function: ConverttoButton
        function ConverttoButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField.Value;
            RHV = app.TurnsRationEditField_2.Value;
            LHV = app.TurnsRationEditField.Value;
            Side1 = app.SideDropDown.Value;
            Side2 = app.SideDropDown_2.Value;
            
            a = RHV/LHV; % Turns ratio
            
            req = app.R_eqohmEditField.Value;
            xeq = app.X_eqohmEditField.Value;
            
            rc = app.R_cohmEditField.Value;
            xm = app.X_cohmEditField.Value;
            
             if strcmp(Side1,'High Voltage Side') && strcmp(Side2,'Low Voltage Side')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C1EditField.Value = a^2 * rc ;
                app.j_Xm1EditField.Value = a^2 * xm;
                app.R_e1EditField.Value = req/a^2;
                app.jEditField.Value = xeq/a^2;
                
            end
            if strcmp(Side1,'Low Voltage Side') && strcmp(Side2,'High Voltage Side')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField_2.Value = req*a^2;
                app.jEditField_2.Value = xeq*a^2;
                
            end
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField_2.Value;
            app.XP = app.jEditField_2.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField.Value;
            app.x_s = app.jEditField.Value;
            
      
            if strcmp(app.ConverttoDropDown.Value,'Step-up Auto Transformer')
                AT_EQCU(app);
            end
            if strcmp(app.ConverttoDropDown.Value,'Step-down Auto Transformer')
                AT_EQCD(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TurnsRationEditField = uieditfield(app.UIFigure, 'numeric');
            app.KVALabel = uilabel(app.UIFigure);
            app.KVALabel.Text = 'KVA';
            app.SecondaryVoltsLabel = uilabel(app.UIFigure);
            app.SecondaryVoltsLabel.Text = 'Secondary Volts';
            app.PrimaryVoltsLabel = uilabel(app.UIFigure);
            app.PrimaryVoltsLabel.Text = 'Primary Volts';
            app.TurnsRationEditFieldLabel = uilabel(app.UIFigure);
            app.TurnsRationEditFieldLabel.Text = ':';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'TRANSFORMER  EQUIVALENT CIRCUIT & PERFORMANCE'};
            app.ObtainEquivalentCircuitButton = uibutton(app.UIFigure, 'push');
            app.ObtainEquivalentCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainEquivalentCircuitButtonPushed, true);
            app.ObtainEquivalentCircuitButton.Text = 'Obtain Equivalent Circuit';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.RatedPowerinKVASEditFieldLabel = uilabel(app.UIFigure);
            app.RatedPowerinKVASEditFieldLabel.Text = 'Rated Power in KVA. S';
            app.RatedPowerinKVASEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurnsRationEditField_2Label = uilabel(app.UIFigure);
            app.TurnsRationEditField_2Label.Text = 'Turns Ration';
            app.TurnsRationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Equivalent  Series  Branch Resistance  and  Leakage  Reactance'};
            app.R_eqohmEditFieldLabel = uilabel(app.UIFigure);
            app.R_eqohmEditFieldLabel.Text = 'R_eq(ohm)';
            app.R_eqohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_eqohmEditFieldLabel = uilabel(app.UIFigure);
            app.X_eqohmEditFieldLabel.Text = 'X_eq(ohm)';
            app.X_eqohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'referred to'};
            app.SideDropDown = uidropdown(app.UIFigure);
            app.SideDropDown.Items = {'High Voltage Side', 'Low Voltage Side'};
            app.SideDropDown.Value = 'High Voltage Side';
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Value = {'Equivalent  Shunt  Branch Resistance  and  Magnetizing  Reactance'};
            app.TextArea_7 = uitextarea(app.UIFigure);
            app.TextArea_7.Value = {'referred to'};
            app.SideDropDown_2 = uidropdown(app.UIFigure);
            app.SideDropDown_2.Items = {'High Voltage Side', 'Low Voltage Side'};
            app.SideDropDown_2.Value = 'Low Voltage Side';
            app.R_cohmLabel = uilabel(app.UIFigure);
            app.R_cohmLabel.Text = 'R_c (ohm)';
            app.R_cohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_cohmEditFieldLabel = uilabel(app.UIFigure);
            app.X_cohmEditFieldLabel.Text = 'X_c (ohm)';
            app.X_cohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_8 = uitextarea(app.UIFigure);
            app.TextArea_8.Value = {'Equivalent Circuit referred to Secondary'};
            app.TextArea_9 = uitextarea(app.UIFigure);
            app.TextArea_9.Value = {'Equivalent Circuit referred to Primary'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'eq_cir1.PNG';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'eq_cirf.PNG';
            app.R_C2EditFieldLabel = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel.Text = 'Load';
            app.R_C2EditFieldLabel_2 = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel_2.Text = 'Load';
            app.TransformerPerformanceButton = uibutton(app.UIFigure, 'push');
            app.TransformerPerformanceButton.ButtonPushedFcn = createCallbackFcn(app, @TransformerPerformanceButtonPushed, true);
            app.TransformerPerformanceButton.Text = 'Transformer Performance';
            app.R_e1EditFieldLabel = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel.Text = 'X_e1';
            app.R_e1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel_2.Text = 'R_e2';
            app.R_e1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_e1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel_3.Text = 'X_e2';
            app.jEditFieldLabel = uilabel(app.UIFigure);
            app.jEditFieldLabel.Text = 'j';
            app.jEditField = uieditfield(app.UIFigure, 'numeric');
            app.j_Xm1EditFieldLabel = uilabel(app.UIFigure);
            app.j_Xm1EditFieldLabel.Text = 'j_Xm1';
            app.j_Xm1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_C1EditFieldLabel = uilabel(app.UIFigure);
            app.R_C1EditFieldLabel.Text = 'R_C1';
            app.R_C1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_e1EditField_2Label = uilabel(app.UIFigure);
            app.R_e1EditField_2Label.Text = 'R_e1';
            app.R_e1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.j_Xm2EditFieldLabel = uilabel(app.UIFigure);
            app.j_Xm2EditFieldLabel.Text = 'j_Xm2';
            app.j_Xm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_C2EditFieldLabel_3 = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel_3.Text = 'R_C2';
            app.R_C2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadPFEditFieldLabel = uilabel(app.UIFigure);
            app.LoadPFEditFieldLabel.Text = 'Load PF';
            app.LoadPFEditField = uieditfield(app.UIFigure, 'numeric');
            app.PFDropDownLabel = uilabel(app.UIFigure);
            app.PFDropDownLabel.Text = 'PF';
            app.PFDropDown = uidropdown(app.UIFigure);
            app.PFDropDown.Items = {'Unity', 'Leading', 'Lagging'};
            app.PFDropDown.Value = 'Leading';
            app.jEditField_2Label = uilabel(app.UIFigure);
            app.jEditField_2Label.Text = 'j';
            app.jEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LoadVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.LoadVoltageEditFieldLabel.Text = 'Load Voltage';
            app.LoadVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConverttoDropDown = uidropdown(app.UIFigure);
            app.ConverttoDropDown.Items = {'Step-up Auto Transformer', 'Step-down Auto Transformer'};
            app.ConverttoDropDown.Value = 'Step-up Auto Transformer';
            app.ConverttoButton = uibutton(app.UIFigure, 'push');
            app.ConverttoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoButtonPushed, true);
            app.ConverttoButton.Text = 'Convert to';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.EXITButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EQCP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end