classdef IM_OCLR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        VdcEditFieldLabel               matlab.ui.control.Label
        VdcEditField                    matlab.ui.control.NumericEditField
        IdcEditFieldLabel               matlab.ui.control.Label
        IdcEditField                    matlab.ui.control.NumericEditField
        VLabel                          matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        PhaseAEditFieldLabel            matlab.ui.control.Label
        PhaseAEditField                 matlab.ui.control.NumericEditField
        PhaseBEditFieldLabel            matlab.ui.control.Label
        PhaseBEditField                 matlab.ui.control.NumericEditField
        PhaseCEditFieldLabel            matlab.ui.control.Label
        PhaseCEditField                 matlab.ui.control.NumericEditField
        ALabel_2                        matlab.ui.control.Label
        ALabel_3                        matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        AppliedVoltageEditFieldLabel    matlab.ui.control.Label
        AppliedVoltageEditField         matlab.ui.control.NumericEditField
        InputPowerEditFieldLabel        matlab.ui.control.Label
        InputPowerEditField             matlab.ui.control.NumericEditField
        WLabel                          matlab.ui.control.Label
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        NumberofPolesEditFieldLabel     matlab.ui.control.Label
        NumberofPolesEditField          matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        SupplyConnectionDropDownLabel   matlab.ui.control.Label
        SupplyConnectionDropDown        matlab.ui.control.DropDown
        DesignDropDownLabel             matlab.ui.control.Label
        DesignDropDown                  matlab.ui.control.DropDown
        ALabel_4                        matlab.ui.control.Label
        TextArea_6                      matlab.ui.control.TextArea
        ALabel_5                        matlab.ui.control.Label
        ALabel_6                        matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        PhaseAEditField_2Label          matlab.ui.control.Label
        PhaseAEditField_2               matlab.ui.control.NumericEditField
        PhaseBEditField_2Label          matlab.ui.control.Label
        PhaseBEditField_2               matlab.ui.control.NumericEditField
        PhaseCEditField_2Label          matlab.ui.control.Label
        PhaseCEditField_2               matlab.ui.control.NumericEditField
        AppliedVoltageEditField_2Label  matlab.ui.control.Label
        AppliedVoltageEditField_2       matlab.ui.control.NumericEditField
        InputPowerEditField_2Label      matlab.ui.control.Label
        InputPowerEditField_2           matlab.ui.control.NumericEditField
        EXITButton                      matlab.ui.control.Button
        ObtainEquivalentCircuitButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        freq;
        poles;
        sct;
        design;
        
        vdc;
        idc;
        
        ian;
        ibn;
        icn;
        vn;
        pnl;
        
        iaf;
        ibf;
        icf;
        vf;
        pnf;
                              
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ObtainEquivalentCircuitButton
        function ObtainEquivalentCircuitButtonPushed(app, event)
            
            
        app.freq = app.FrequencyEditField.Value;
        app.poles = app.NumberofPolesEditField.Value; 
        app.sct = app.SupplyConnectionDropDown.Value;
        app.design = app.DesignDropDown.Value;
        
        app.vdc = app.VdcEditField.Value;
        app.idc = app.IdcEditField.Value;
        
        app.ian = app.PhaseAEditField.Value;
        app.ibn = app.PhaseBEditField.Value;
        app.icn = app.PhaseCEditField.Value;
        app.vn = app.AppliedVoltageEditField.Value;
        app.pnl = app.InputPowerEditField.Value;
        
        app.iaf = app.PhaseAEditField_2.Value;
        app.ibf = app.PhaseBEditField_2.Value;
        app.icf = app.PhaseCEditField_2.Value;
        app.vf  = app.AppliedVoltageEditField_2.Value;
        app.pnf = app.InputPowerEditField_2.Value;
            
            
        IM_Out(app);
        
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Open Circuit and Locked Rotor Test'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'DC Test'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Open Circuit / No Load Test'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Locked Rotor Test'};
            app.VdcEditFieldLabel = uilabel(app.UIFigure);
            app.VdcEditFieldLabel.Text = 'Vdc';
            app.VdcEditField = uieditfield(app.UIFigure, 'numeric');
            app.VdcEditField.Value = 13.5;
            app.IdcEditFieldLabel = uilabel(app.UIFigure);
            app.IdcEditFieldLabel.Text = 'Idc';
            app.IdcEditField = uieditfield(app.UIFigure, 'numeric');
            app.IdcEditField.Value = 64;
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Line Current of'};
            app.PhaseAEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseAEditFieldLabel.Text = 'Phase A';
            app.PhaseAEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseAEditField.Value = 22;
            app.PhaseBEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseBEditFieldLabel.Text = 'Phase B';
            app.PhaseBEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseBEditField.Value = 22;
            app.PhaseCEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseCEditFieldLabel.Text = 'Phase C';
            app.PhaseCEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseCEditField.Value = 22;
            app.ALabel_2 = uilabel(app.UIFigure);
            app.ALabel_2.Text = 'A';
            app.ALabel_3 = uilabel(app.UIFigure);
            app.ALabel_3.Text = 'A';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.AppliedVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.AppliedVoltageEditFieldLabel.Text = 'Applied Voltage';
            app.AppliedVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.AppliedVoltageEditField.Value = 208;
            app.InputPowerEditFieldLabel = uilabel(app.UIFigure);
            app.InputPowerEditFieldLabel.Text = 'Input Power';
            app.InputPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputPowerEditField.Value = 1200;
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 60;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.NumberofPolesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPolesEditFieldLabel.Text = 'Number of Poles';
            app.NumberofPolesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPolesEditField.Value = 6;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMdesign.png';
            app.SupplyConnectionDropDownLabel = uilabel(app.UIFigure);
            app.SupplyConnectionDropDownLabel.Text = 'Supply Connection';
            app.SupplyConnectionDropDown = uidropdown(app.UIFigure);
            app.SupplyConnectionDropDown.Items = {'Y', 'D'};
            app.SupplyConnectionDropDown.Value = 'Y';
            app.DesignDropDownLabel = uilabel(app.UIFigure);
            app.DesignDropDownLabel.Text = 'Design';
            app.DesignDropDown = uidropdown(app.UIFigure);
            app.DesignDropDown.Items = {'A', 'B', 'C', 'D'};
            app.DesignDropDown.Value = 'B';
            app.ALabel_4 = uilabel(app.UIFigure);
            app.ALabel_4.Text = 'A';
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Value = {'Line Current of'};
            app.ALabel_5 = uilabel(app.UIFigure);
            app.ALabel_5.Text = 'A';
            app.ALabel_6 = uilabel(app.UIFigure);
            app.ALabel_6.Text = 'A';
            app.VLabel_3 = uilabel(app.UIFigure);
            app.VLabel_3.Text = 'V';
            app.WLabel_2 = uilabel(app.UIFigure);
            app.WLabel_2.Text = 'W';
            app.PhaseAEditField_2Label = uilabel(app.UIFigure);
            app.PhaseAEditField_2Label.Text = 'Phase A';
            app.PhaseAEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PhaseAEditField_2.Value = 64.5;
            app.PhaseBEditField_2Label = uilabel(app.UIFigure);
            app.PhaseBEditField_2Label.Text = 'Phase B';
            app.PhaseBEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PhaseBEditField_2.Value = 64.5;
            app.PhaseCEditField_2Label = uilabel(app.UIFigure);
            app.PhaseCEditField_2Label.Text = 'Phase C';
            app.PhaseCEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PhaseCEditField_2.Value = 64.5;
            app.AppliedVoltageEditField_2Label = uilabel(app.UIFigure);
            app.AppliedVoltageEditField_2Label.Text = 'Applied Voltage';
            app.AppliedVoltageEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AppliedVoltageEditField_2.Value = 24.6;
            app.InputPowerEditField_2Label = uilabel(app.UIFigure);
            app.InputPowerEditField_2Label.Text = 'Input Power';
            app.InputPowerEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.InputPowerEditField_2.Value = 2200;
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.ObtainEquivalentCircuitButton = uibutton(app.UIFigure, 'push');
            app.ObtainEquivalentCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainEquivalentCircuitButtonPushed, true);
            app.ObtainEquivalentCircuitButton.Text = 'Obtain Equivalent Circuit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IM_OCLR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end