classdef IM_Out < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea_2                      matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        StatorResistanceR1EditFieldLabel  matlab.ui.control.Label
        StatorResistanceR1EditField     matlab.ui.control.NumericEditField
        RotorResistanceR2EditFieldLabel  matlab.ui.control.Label
        RotorResistanceR2EditField      matlab.ui.control.NumericEditField
        ApproximateX1EditFieldLabel     matlab.ui.control.Label
        ApproximateX1EditField          matlab.ui.control.NumericEditField
        ApproximateX2EditFieldLabel     matlab.ui.control.Label
        ApproximateX2EditField          matlab.ui.control.NumericEditField
        MagnitizingReactanceEditFieldLabel  matlab.ui.control.Label
        MagnitizingReactanceEditField   matlab.ui.control.NumericEditField
        CoreresistanceRCEditFieldLabel  matlab.ui.control.Label
        CoreresistanceRCEditField       matlab.ui.control.NumericEditField
        TheveninVoltageEditFieldLabel   matlab.ui.control.Label
        TheveninVoltageEditField        matlab.ui.control.NumericEditField
        TheveninResistanceEditFieldLabel  matlab.ui.control.Label
        TheveninResistanceEditField     matlab.ui.control.NumericEditField
        TheveninReactanceEditFieldLabel  matlab.ui.control.Label
        TheveninReactanceEditField      matlab.ui.control.NumericEditField
        SynchronousSpeedEditFieldLabel  matlab.ui.control.Label
        SynchronousSpeedEditField       matlab.ui.control.NumericEditField
        StartingTorqueEditFieldLabel    matlab.ui.control.Label
        StartingTorqueEditField         matlab.ui.control.NumericEditField
        MaximumTorqueEditFieldLabel     matlab.ui.control.Label
        MaximumTorqueEditField          matlab.ui.control.NumericEditField
        SlipforMaxTorqueEditFieldLabel  matlab.ui.control.Label
        SlipforMaxTorqueEditField       matlab.ui.control.NumericEditField
        EXITButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tapp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, demo)
            app.tapp = demo;
            
            f= app.tapp.freq;
            Poles= app.tapp.poles;
            pattern= app.tapp.sct;
            design= app.tapp.design;
            
            Vdc = app.tapp.vdc;
            Idc = app.tapp.idc;
            
            Ia_nl = app.tapp.ian;
            Ib_nl = app.tapp.ibn;
            Ic_nl = app.tapp.icn;
            VL_nl = app.tapp.vn;
            Pin_nl = app.tapp.pnl;
            
            Ia_fl = app.tapp.iaf;
            Ib_fl = app.tapp.ibf;
            Ic_fl = app.tapp.icf;
            VL_fl = app.tapp.vf;
            Pin_fl = app.tapp.pnf;
            
            
            %ckt values
%             f=input("Frequency:");
%             Poles=input("Number of Poles:");
%             design=input("Design(A/B/C/D):");
%             pattern=input("Supply Connection Wye(Y) or Delta(D):");
            
            %DC test input
%             disp("DC test input:")
%             Vdc=input("Vdc:");
%             Idc=input("Idc:");
            
            %Blocked Rotor test Data
%             disp("Blocked rotor test data:")
%             
%             Ia_fl=input("Line current of phase A at full load:");   % Line current of phase A at full load
%             Ib_fl=input("Line current of phase B at full load:");   % Line current of phase B at full load
%             Ic_fl=input("Line current of phase C at full load:");   % Line current of phase C at full load
            I2=(Ia_fl+Ib_fl+Ic_fl)/3;           % Average per phase current
            
            % Full load Applied Voltage
            
%             VL_fl=input("Applied Voltage at Full load:");
%             
%             Pin_fl=input("Full load input power:");                         % Full Load input Power
            
            % No load Test Data
%             disp("No-load test data:")
%             
%             %No load Currents
%             Ia_nl=input("Line current of phase A at no load:");    % Line current of phase A at no load
%             Ib_nl=input("Line current of phase B at no load:");   % Line current of phase B at no load
%             Ic_nl=input("Line current of phase C at no load:");   % Line current of phase C at no load
            I1=(Ia_nl+Ib_nl+Ic_nl)/3;           % Average per phase current
            
            %No load Applied Voltage
%             VL_nl=input("Applied Voltage at no load:");
%             
%             Pin_nl=input("Input power at no load:");                         % No Load input Power
            
            %starting of Calculations
            
            if pattern=="Y"
                %DC Test Calculation
                
                R1=Vdc/(2*Idc);  % Stator resistance
                %disp(['Stator resistance         = R1   = ' num2str(R1) ' ohms']);
                app.StatorResistanceR1EditField.Value = R1;
                app.EditField.Value = R1;
                %blocked-rotor test calculation
                
                Vfl=VL_fl/sqrt(3);                  % Full load phase voltage
                Zfl=Vfl/I2;                         % Full load equivalent impedence
                PFfl=Pin_fl/(sqrt(3)*VL_fl*I2);     % Full load power factor
                angle_fl=acos(PFfl)*180/pi;         % Full load Power factor Angle
                Rfl=Zfl*PFfl;                       % Full load equivalent resistance
                R2=Rfl-R1;                          % Rotor resistance
                Xfl_15=Zfl*sin(acos(PFfl));         % Full load equivalent raectance at 50Hz
                Xfl_60=(60/15)*Xfl_15;              %Full load equivalent reactance at 60Hz
                Zfl_c=Rfl+Xfl_60*1i;                 % Full load impedence in complex form
                if design=="A"
                    X1=.5*Xfl_60;
                    X2=.5*Xfl_60;
                end
                if design=="B"
                    X1=.4*Xfl_60;
                    X2=.6*Xfl_60;
                end
                if design=="C"
                    X1=.3*Xfl_60;
                    X2=.7*Xfl_60;
                end
                if design=="D"
                    X1=.5*Xfl_60;
                    X2=.5*Xfl_60;
                end
                
%                 disp([' ']);
%                 disp(['Full load calculations for trasformer equivalent circuit'])
%                 disp(['--------------------------------------------------------'])
%                 disp(['Full laod Line Voltage   = VLfl = ' num2str(VL_fl) ' V']);
%                 disp(['Full load Phase Voltage  = Vfl  = ' num2str(Vfl) ' ohms'])
%                 disp(['Full laod Current        = I2   = ' num2str(I2) ' A']);
%                 disp(['Full laod Impedance      = Zfl  = ' num2str(Zfl) ' ohms']);
%                 disp(['Full load Power factor   = PFfl = ' num2str(PFfl) ' ']);
%                 disp(['Power factor angle       = phi  = ' num2str(angle_fl) ' degree']);
%                 disp(['Rotor resistance         = R2   = ' num2str(R2) ' ohms']);
                  app.RotorResistanceR2EditField.Value = R2;
                  app.EditField_4.Value = R2;
%                 disp(['Approximate X1           = X1   = ' num2str(X1) ' ohms']);
                  app.ApproximateX1EditField.Value = abs(X1);
                  app.EditField_2.Value = X1;
%                 disp(['Approximate X2           = X2   = ' num2str(X2) ' ohms']);
                  app.EditField_3.Value = X2;
                  app.ApproximateX2EditField.Value = abs(X2);
                
                % Calculation using no-load test data
                Vp=VL_nl/sqrt(3);                   % Calculate phase voltage for per phase circuit
                Znl=Vp/I1;                          % No-load Impedance
                P_scl=3*I1*I1*R1;                   % Stator copper losses
                P_rot=Pin_nl-P_scl;                 % Rotational Losses
                PF_nl=Pin_nl/(sqrt(3)*VL_nl*I1);    % No load power factor
                angle_nl=acos(PF_nl)*180/pi;        % No load power factor angle in degrees
                angle_nl_r=acos(PF_nl);             % No load power factor angle in radians
                Im=I1*sin(angle_nl_r);              % Current through Xm
                Ic=I1*PF_nl;                        % Current through Rc
                Xm=Znl-X1;                          % value of magnetizing reactance
                Vm=Xm*Im;                           % Magnetizing branch voltage
                Rc=Vm/Ic;                           % Value of core resistance
                Zm=(1i*Xm*Rc)/(1i*Xm+Rc);           % Magnitising branch impedance
                Rnl=Znl*PF_nl;                      % No load resistance from Znl and power factor
                Xnl=Znl*sin(angle_nl_r);            % No load reactance from Znl and power factor
                Znl_complex=Rnl+1i*Xnl;             % No load impedance in complex form
                
%                 disp(['No load calculations for trasformer equivalent circuit'])
%                 disp(['------------------------------------------------------'])
%                 disp(['Line to line voltage     = Vl   = ' num2str(VL_nl) ' V']);
%                 disp(['Phase Voltage at no load = Vp   = ' num2str(Vp) ' V']);
%                 disp(['No load Current          = I1   = ' num2str(I1) ' A']);
%                 disp(['No load Input Power Pin  = Pin  = ' num2str(Pin_nl) ' W']);
%                 disp(['No load Impedance Znl    = Znl  = ' num2str(Znl) ' ohms']);
%                 disp(['No Load power factor     = PFnl = ' num2str(PF_nl)]);
%                 disp(['Power factor angle       = phi  = ' num2str(angle_nl) ' degree']);
%                 %disp(['Magnitizing branch volt  = Vm   = ' num2str(Vm) ' V']);
%                 disp(['Current through Xm       = Im   = ' num2str(Im) ' A']);
%                 disp(['Current through Rc       = Ic   = ' num2str(Ic) ' A']);
                  app.CoreresistanceRCEditField.Value = Rc;
%                 disp(['Magnitizing Reactance    = Xm   = ' num2str(Xm) ' ohms']);
                app.EditField_5.Value = abs(Xm);
                app.MagnitizingReactanceEditField.Value = abs(Xm);
%                 disp(['Core Resistance          = Rc   = ' num2str(Rc) ' ohms']);
                %disp(['Magnitizing Impedance    = Zm   = ' num2str(Zm) ' ohms']);
%                 disp(['Stator Copper Losses     = Pscl = ' num2str(P_scl) ' W']);
%                 disp(['Rotational Losses        = Prot = ' num2str(P_rot) ' W']);
                
                % Calculation of thevenin equivalent circuit parameters
                
                Vth=(Xm/(X1+Xm))*Vp;        % calculating thevenin equivalent voltage
                Rth=(Xm/(X1+Xm))^2*R1;      % calculation of thevenin resistance
                Xth=X1;                     % calculation of thevenin reactance
                Ns=120*f/Poles;             % calculation of synchronous frequency
                ws=Ns*2*pi/60;              % converting 'rpm' into 'rad/sec'
                
                Tstart=(3*Vth^2*R2)/(ws*((Rth+R2)^2+(Xth+X2)^2));     % Caculating starting torque of motor
                Smax=R2/sqrt(Rth^2+(Xth+X2)^2);                       % Slip for maximum torque
                Tmax=(3*Vth^2)/(2*ws*(Rth+sqrt(Rth^2+(Xth+X2)^2)));   % Calulating maximum torque
                
%                 disp([' ']);
%                 disp(['Thevenin Calculation from Induction motor equivalent cicuit'])
%                 disp(['-----------------------------------------------------------'])
%                 disp(['Thevenin Voltage         = Vth  = ' num2str(Vth) ' V'])
%                 disp(['Thevenin Resistance      = Rth  = ' num2str(Rth) ' ohms'])
%                 disp(['Thevenin Reactance       = Xth  = ' num2str(Xth) ' ohms'])
                app.TheveninVoltageEditField.Value = Vth;
                app.TheveninResistanceEditField.Value = Rth;
                app.TheveninReactanceEditField.Value = Xth;
                
%                 disp([' ']);
%                 disp(['Calculation of starting and pull-out torque '])
%                 disp(['-------------------------------------------'])
%                 disp(['Rated frequency          = f    = ' num2str(f) ' Hz'])
%                 disp(['No. of poles             = P    = ' num2str(Poles) ' '])
%                 disp(['Synchronous speed        = Ns   = ' num2str(Ns) ' rpm'])
%                 disp(['Synchronous speed        = Ws   = ' num2str(ws) ' rad/sec'])
%                 disp(['Starting Torque, Tstart  = Ts   = ' num2str(Tstart) ' N.m'])
%                 disp(['Slip for Tmax            = Sm   = ' num2str(Smax) ' '])
%                 disp(['Maximum Torque, Tmax     = Tm   = ' num2str(Tmax) ' N.m'])
                 app.SynchronousSpeedEditField.Value = Ns;
                 app.StartingTorqueEditField.Value = Tstart;
                 app.MaximumTorqueEditField.Value = Tmax;
                 app.SlipforMaxTorqueEditField.Value = Smax;
                
                % Plotting torque speed characteristics
                s=1:-0.001:0;
                s(1001)=0.001;
                Nm=(1-s)*Ns;
                T_ind = zeros(1,1000);
                for ii=1:1000
                    T_ind(ii)=(3*Vth^2*R2/s(ii))/(ws*((Rth+R2/s(ii))^2+(Xth+X2)^2));
                end
                T_ind(1001)=T_ind(1000);
                plot(app.UIAxes,Nm,T_ind);
                axis(app.UIAxes,[0,Ns,0,Tmax+1]);
                xlabel(app.UIAxes,'Rotor Speed (rpm)');
                ylabel(app.UIAxes,'Torque induced N.m');
                title(app.UIAxes,'Torque speed curve of induction motor');
                Nmax=(1-Smax)*Ns;
                x=[Nmax, Nmax];
                y=[0,Tmax];
                hold(app.UIAxes);
                line(app.UIAxes,x,y,'Color','r','LineStyle',':','LineWidth',1)
                text(app.UIAxes,Nmax,Tmax+0.35,'Pull out Torque');
                plot(app.UIAxes,Nmax,Tmax,'Marker','o')
                text(app.UIAxes,Nmax+10,0.35,'Smax')
                plot(app.UIAxes,Nmax,0,'Marker','o')
                text(app.UIAxes,15,Tstart-0.25,'Starting Torque');
                plot(app.UIAxes,0,Tstart,'Marker','o')
            end
                
            if pattern=="D"
                %for Delta Connection
                %DC Test Calculation
                R1=(3/2)*(Vdc/Idc); %for delta connection
                %disp(['Stator resistance         = R1   = ' num2str(R1) ' ohms']);
                app.StatorResistanceR1EditField.Value = R1;
                app.EditField.Value = R1;
                
                %blocked-rotor test calculation
                
                Vfl=VL_fl;                          % Full load phase voltage
                I2=I2/sqrt(3);                      % Phase Current
                Zfl=Vfl/I2;                         % Full load equivalent impedence
                PFfl=Pin_fl/(3*VL_fl*I2);           % Full load power factor
                angle_fl=acos(PFfl)*180/pi;         % Full load Power factor Angle
                Rfl=Zfl*PFfl;                       % Full load equivalent resistance
                R2=Rfl-R1;                          % Rotor resistance
                Xfl_15=Zfl*sin(acos(PFfl));         % Full load equivalent raectance at 50Hz
                Xfl_60=(60/15)*Xfl_15;              %Full load equivalent reactance at 60Hz
                Zfl_c=Rfl+Xfl_60*1i;                 % Full load impedence in complex form
                if design=="A"
                    X1=.5*Xfl_60;
                    X2=.5*Xfl_60;
                end
                if design=="B"
                    X1=.4*Xfl_60;
                    X2=.6*Xfl_60;
                end
                if design=="C"
                    X1=.3*Xfl_60;
                    X2=.7*Xfl_60;
                end
                if design=="D"
                    X1=.5*Xfl_60;
                    X2=.5*Xfl_60;
                end
                
%                 disp([' ']);
%                 disp(['Full load calculations for trasformer equivalent circuit'])
%                 disp(['--------------------------------------------------------'])
%                 disp(['Full laod Line Voltage   = VLfl = ' num2str(VL_fl) ' V']);
%                 disp(['Full load Phase Voltage  = Vfl  = ' num2str(Vfl) ' ohms'])
%                 disp(['Full laod Current        = I2   = ' num2str(I2) ' A']);
%                 disp(['Full laod Impedance      = Zfl  = ' num2str(Zfl) ' ohms']);
%                 disp(['Full load Power factor   = PFfl = ' num2str(PFfl) ' ']);
%                 disp(['Power factor angle       = phi  = ' num2str(angle_fl) ' degree']);
%                 disp(['Rotor resistance         = R2   = ' num2str(R2) ' ohms']);
%                 disp(['Approximate X1           = X1   = ' num2str(X1) ' ohms']);
%                 disp(['Approximate X2           = X2   = ' num2str(X2) ' ohms']);

                  app.RotorResistanceR2EditField.Value = R2;
                  app.EditField_4.Value = R2;

                  app.ApproximateX1EditField.Value = X1;
                  app.EditField_2.Value = X1;

                  app.EditField_3.Value = X2;
                  app.ApproximateX2EditField.Value = X2;


                % Calculation using no-load test data
                Vp=VL_nl;                           % Calculate phase voltage for per phase circuit
                I1=I1/sqrt(3);                      % Phase Current
                Znl=Vp/I1;                          % No-load Impedance
                P_scl=3*I1*I1*R1;                   % Stator copper losses
                P_rot=Pin_nl-P_scl;                 % Rotational Losses
                PF_nl=Pin_nl/(3*VL_nl*I1);    % No load power factor
                angle_nl=acos(PF_nl)*180/pi;        % No load power factor angle in degrees
                angle_nl_r=acos(PF_nl);             % No load power factor angle in radians
                Im=I1*sin(angle_nl_r);              % Current through Xm
                Ic=I1*PF_nl;                        % Current through Rc
                Xm=Znl-X1;                          % value of magnetizing reactance
                Vm=Xm*Im;                           % Magnetizing branch voltage
                Rc=Vm/Ic;                           % Value of core resistance
                Zm=(1i*Xm*Rc)/(1i*Xm+Rc);           % Magnitising branch impedance
                Rnl=Znl*PF_nl;                      % No load resistance from Znl and power factor
                Xnl=Znl*sin(angle_nl_r);            % No load reactance from Znl and power factor
                %Znl_complex=Rnl+1i*Xnl;             % No load impedance in complex form
                
%                 disp(['No load calculations for trasformer equivalent circuit'])
%                 disp(['------------------------------------------------------'])
%                 disp(['Line to line voltage     = Vl   = ' num2str(VL_nl) ' V']);
%                 disp(['Phase Voltage at no load = Vp   = ' num2str(Vp) ' V']);
%                 disp(['No load Current          = I1   = ' num2str(I1) ' A']);
%                 disp(['No load Input Power Pin  = Pin  = ' num2str(Pin_nl) ' W']);
%                 disp(['No load Impedance Znl    = Znl  = ' num2str(Znl) ' ohms']);
%                 disp(['No Load power factor     = PFnl = ' num2str(PF_nl)]);
%                 disp(['Power factor angle       = phi  = ' num2str(angle_nl) ' degree']);
%                 %disp(['Magnitizing branch volt  = Vm   = ' num2str(Vm) ' V']);
%                 disp(['Current through Xm       = Im   = ' num2str(Im) ' A']);
%                 disp(['Current through Rc       = Ic   = ' num2str(Ic) ' A']);
%                 disp(['Magnitizing Reactance    = Xm   = ' num2str(Xm) ' ohms']);
%                 disp(['Core Resistance          = Rc   = ' num2str(Rc) ' ohms']);
%                 %disp(['Magnitizing Impedance    = Zm   = ' num2str(Zm) ' ohms']);
%                 disp(['Stator Copper Losses     = Pscl = ' num2str(P_scl) ' W']);
%                 disp(['Rotational Losses        = Prot = ' num2str(P_rot) ' W']);
                app.CoreresistanceRCEditField.Value = Rc;
                app.EditField_5.Value = Xm;
                app.MagnitizingReactanceEditField.Value = Xm;


                % Calculation of thevenin equivalent circuit parameters
                
                Vth=(Xm/(X1+Xm))*Vp;        % calculating thevenin equivalent voltage
                Rth=(Xm/(X1+Xm))^2*R1;      % calculation of thevenin resistance
                Xth=X1;                     % calculation of thevenin reactance
                Ns=120*f/Poles;             % calculation of synchronous frequency
                ws=Ns*2*pi/60;              % converting 'rpm' into 'rad/sec'
                
                Tstart=(3*Vth^2*R2)/(ws*((Rth+R2)^2+(Xth+X2)^2));     % Caculating starting torque of motor
                Smax=R2/sqrt(Rth^2+(Xth+X2)^2);                       % Slip for maximum torque
                Tmax=(3*Vth^2)/(2*ws*(Rth+sqrt(Rth^2+(Xth+X2)^2)));   % Calulating maximum torque
                
%                 disp([' ']);
%                 disp(['Thevenin Calculation from Induction motor equivalent cicuit'])
%                 disp(['-----------------------------------------------------------'])
%                 disp(['Thevenin Voltage         = Vth  = ' num2str(Vth) ' V'])
%                 disp(['Thevenin Resistance      = Rth  = ' num2str(Rth) ' ohms'])
%                 disp(['Thevenin Reactance       = Xth  = ' num2str(Xth) ' ohms'])
%                 
%                 disp([' ']);
%                 disp(['Calculation of starting and pull-out torque '])
%                 disp(['-------------------------------------------'])
%                 disp(['Rated frequency          = f    = ' num2str(f) ' Hz'])
%                 disp(['No. of poles             = P    = ' num2str(Poles) ' '])
%                 disp(['Synchronous speed        = Ns   = ' num2str(Ns) ' rpm'])
%                 disp(['Synchronous speed        = Ws   = ' num2str(ws) ' rad/sec'])
%                 disp(['Starting Torque, Tstart  = Ts   = ' num2str(Tstart) ' N.m'])
%                 disp(['Slip for Tmax            = Sm   = ' num2str(Smax) ' '])
%                 disp(['Maximum Torque, Tmax     = Tm   = ' num2str(Tmax) ' N.m'])

                app.TheveninVoltageEditField.Value = Vth;
                app.TheveninResistanceEditField.Value = Rth;
                app.TheveninReactanceEditField.Value = Xth;
                
                 app.SynchronousSpeedEditField.Value = Ns;
                 app.StartingTorqueEditField.Value = Tstart;
                 app.MaximumTorqueEditField.Value = Tmax;
                 app.SlipforMaxTorqueEditField.Value = Smax;
                
                
                % Plotting torque speed characteristics
                s=1:-0.001:0;
                s(1001)=0.001;
                Nm=(1-s)*Ns;
                T_ind = zeros(1,1000);
                for ii=1:1000
                    T_ind(ii)=(3*Vth^2*R2/s(ii))/(ws*((Rth+R2/s(ii))^2+(Xth+X2)^2));
                end
                T_ind(1001)=T_ind(1000);
                plot(app.UIAxes,Nm,T_ind);
                axis(app.UIAxes,[0,Ns,0,Tmax+1]);
                xlabel(app.UIAxes,'Rotor Speed (rpm)');
                ylabel(app.UIAxes,'Torque induced N.m');
                title(app.UIAxes,'Torque speed curve of induction motor');
                Nmax=(1-Smax)*Ns;
                x=[Nmax, Nmax];
                y=[0,Tmax];
                hold(app.UIAxes);
                line(app.UIAxes,x,y,'Color','r','LineStyle',':','LineWidth',1)
                text(app.UIAxes,Nmax,Tmax+0.35,'Pull out Torque');
                plot(app.UIAxes,Nmax,Tmax,'Marker','o')
                text(app.UIAxes,Nmax+10,0.35,'Smax')
                plot(app.UIAxes,Nmax,0,'Marker','o')
                text(app.UIAxes,15,Tstart-0.25,'Starting Torque');
                plot(app.UIAxes,0,Tstart,'Marker','o')
            end
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Equivalent Circuit & Motor Performance'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMEQ.png';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.StatorResistanceR1EditFieldLabel = uilabel(app.UIFigure);
            app.StatorResistanceR1EditFieldLabel.Text = 'Stator Resistance(R1)';
            app.StatorResistanceR1EditField = uieditfield(app.UIFigure, 'numeric');
            app.RotorResistanceR2EditFieldLabel = uilabel(app.UIFigure);
            app.RotorResistanceR2EditFieldLabel.Text = 'Rotor Resistance(R2)';
            app.RotorResistanceR2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ApproximateX1EditFieldLabel = uilabel(app.UIFigure);
            app.ApproximateX1EditFieldLabel.Text = 'Approximate X1';
            app.ApproximateX1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ApproximateX2EditFieldLabel = uilabel(app.UIFigure);
            app.ApproximateX2EditFieldLabel.Text = 'Approximate X2';
            app.ApproximateX2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnitizingReactanceEditFieldLabel = uilabel(app.UIFigure);
            app.MagnitizingReactanceEditFieldLabel.Text = 'Magnitizing Reactance';
            app.MagnitizingReactanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoreresistanceRCEditFieldLabel = uilabel(app.UIFigure);
            app.CoreresistanceRCEditFieldLabel.Text = 'Core resistance (RC)';
            app.CoreresistanceRCEditField = uieditfield(app.UIFigure, 'numeric');
            app.TheveninVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.TheveninVoltageEditFieldLabel.Text = 'Thevenin Voltage';
            app.TheveninVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.TheveninResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.TheveninResistanceEditFieldLabel.Text = 'Thevenin  Resistance';
            app.TheveninResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.TheveninReactanceEditFieldLabel = uilabel(app.UIFigure);
            app.TheveninReactanceEditFieldLabel.Text = 'Thevenin Reactance';
            app.TheveninReactanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SynchronousSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SynchronousSpeedEditFieldLabel.Text = 'Synchronous Speed';
            app.SynchronousSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.StartingTorqueEditFieldLabel.Text = 'Starting Torque';
            app.StartingTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumTorqueEditFieldLabel.Text = 'Maximum Torque';
            app.MaximumTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlipforMaxTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.SlipforMaxTorqueEditFieldLabel.Text = 'Slip for Max Torque';
            app.SlipforMaxTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IM_Out(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end