classdef IM_PC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        TextArea    matlab.ui.control.TextArea
        EXITButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
        UIAxes_2    matlab.ui.control.UIAxes
        UIAxes_3    matlab.ui.control.UIAxes
        UIAxes_4    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tapp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, demo)
           app.tapp = demo;
           
            r1=app.tapp.R1 ;
            x1=app.tapp.X1 ;
            r2=app.tapp.R2 ;
            x2=app.tapp.X2 ;
            xm=app.tapp.XM ;
            
            v_phase=app.tapp.AV ;
            f = app.tapp.F   ;
            Poles = app.tapp.P ;
            
            p_fw= app.tapp.pfw;
            p_core = app.tapp.pcore;
            p_misc = app.tapp.pmisc ;   
           
                        
            n_sync = 120*f/Poles;              % Synchronous speed (r/min)
            w_sync = n_sync*2*pi/60;             % Synchronous speed (rad/s)
            
            % Calculate the Thevenin voltage and impedance from Equations
            % 7-41a and 7-43.
            v_th = v_phase * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            z_th = ((1i*xm) * (r1 + 1i*x1)) / (r1 + 1i*(x1 + xm));
            r_th = real(z_th);
            x_th = imag(z_th);
            
            Tstart=(3*v_th^2*r2)/(w_sync*((r_th+r2)^2+(x_th+x2)^2));     % Caculating starting torque of motor
            Smax=r2/sqrt(r_th^2+(x_th+x2)^2);                            % Slip for maximum torque
            Tmax=(3*v_th^2)/(2*w_sync*(r_th+sqrt(r_th^2+(x_th+x2)^2)));  % Calulating maximum torque
            nm_max=(1-Smax)*n_sync;                                      % rotor speed for maximum torque


            % Now calculate the torque-speed characteristic for many
            % slips between 0 and 1.  Note that the first slip value
            % is set to 0.001 instead of exactly 0 to avoid divide-
            % by-zero problems.
            s = (0:.1:50) / 50;           % Slip
            s(1) = 0.001;
            nm = (1 - s) * n_sync;       % Mechanical speed rpm
            wm = (1-s) * w_sync;         % Mechanical speed rps
            n = length(s);
            
            % Calculate torque for original rotor resistance
            for ii = 1:length(s)
                %torque speed characteristics
                t_ind1(ii) = (3 * v_th^2 * r2 / s(ii)) / ...
                    (w_sync * ((r_th + r2/s(ii))^2 + (x_th + x2)^2) );
                
                %Power converted
                p_conv(ii) = t_ind1(ii) * wm(ii);
                
                %Power output
                p_out(ii) = p_conv(ii) - p_fw - p_core - p_misc;
                
                %Power input
                zf = 1 / ( 1/(1i*xm) + 1/(r2/s(ii)+1i*x2) );
                ia = v_phase / ( r1 + 1i*x1 + zf );
                p_in(ii) = 3 * v_phase * abs(ia) * cos(atan(imag(ia)/real(ia)));
                
                % Efficiency
                eff(ii) = p_out(ii) / p_in(ii) * 100;
                
            end
            
            % Plot the torque-speed curve
            
            plot(app.UIAxes,nm,t_ind1,'Color','k','LineWidth',2.0);
            xlabel(app.UIAxes,'\itn_{m}','Fontweight','Bold');
            ylabel(app.UIAxes,'\tau_{ind}','Fontweight','Bold');
            title (app.UIAxes,'Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
       
            % Output power Vs speed graph
           
            plot(app.UIAxes_2,nm,p_conv/1000,'r-','LineWidth',2.0);
            xlabel(app.UIAxes_2,'\bf\itn_{m} \rm\bf(r/min)');
            ylabel(app.UIAxes_2,'\bf\itP\rm\bf_{CONV} (kW)');
            title (app.UIAxes_2,'\bf Power Converted versus Speed');
     
            
            %plot output power versus speed
           
            plot(app.UIAxes_3,nm,p_out/1000,'g-','LineWidth',2.0);
            xlabel(app.UIAxes_3,'\bf\itn_{m} \rm\bf(r/min)');
            ylabel(app.UIAxes_3,'\bf\itP\rm\bf_{out} (kW)');
            title (app.UIAxes_3,'\bf Output Power versus Speed');
     
            
            %plot the efficiency
            
            plot(app.UIAxes_4,nm,eff,'m-','LineWidth',2.0);
            xlabel(app.UIAxes_4,'\bf\itn_{m} \rm\bf(r/min)');
            ylabel(app.UIAxes_4,'\bf\eta (%)');
            title (app.UIAxes_4,'\bf Efficiency versus Speed');


        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Performance Curves'};
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IM_PC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end