classdef IMchar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea_2                      matlab.ui.control.TextArea
        StatorResistanceEditFieldLabel  matlab.ui.control.Label
        StatorResistanceEditField       matlab.ui.control.NumericEditField
        StatorReactanceEditFieldLabel   matlab.ui.control.Label
        StatorReactanceEditField        matlab.ui.control.NumericEditField
        RotorResistanceEditFieldLabel   matlab.ui.control.Label
        RotorResistanceEditField        matlab.ui.control.NumericEditField
        RotorReactanceEditFieldLabel    matlab.ui.control.Label
        RotorReactanceEditField         matlab.ui.control.NumericEditField
        MagnetizingBranchReactanceLabel  matlab.ui.control.Label
        MagnetizingBranchReactanceEditField  matlab.ui.control.NumericEditField
        AppliedLineVoltageEditFieldLabel  matlab.ui.control.Label
        AppliedLineVoltageEditField     matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        NumberofPolesEditFieldLabel     matlab.ui.control.Label
        NumberofPolesEditField          matlab.ui.control.NumericEditField
        ohmLabel                        matlab.ui.control.Label
        ohmLabel_2                      matlab.ui.control.Label
        ohmLabel_3                      matlab.ui.control.Label
        ohmLabel_4                      matlab.ui.control.Label
        ohmLabel_5                      matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        TheveninVoltageEditFieldLabel   matlab.ui.control.Label
        TheveninVoltageEditField        matlab.ui.control.NumericEditField
        TheveninResistanceEditFieldLabel  matlab.ui.control.Label
        TheveninResistanceEditField     matlab.ui.control.NumericEditField
        TheveninReactanceEditFieldLabel  matlab.ui.control.Label
        TheveninReactanceEditField      matlab.ui.control.NumericEditField
        SynchronousSpeedEditFieldLabel  matlab.ui.control.Label
        SynchronousSpeedEditField       matlab.ui.control.NumericEditField
        StartingTorqueEditFieldLabel    matlab.ui.control.Label
        StartingTorqueEditField         matlab.ui.control.NumericEditField
        MaximumTorqueEditFieldLabel     matlab.ui.control.Label
        MaximumTorqueEditField          matlab.ui.control.NumericEditField
        SlipforMaxTorqueEditFieldLabel  matlab.ui.control.Label
        SlipforMaxTorqueEditField       matlab.ui.control.NumericEditField
        ObtainCharacteristicsButton     matlab.ui.control.Button
        PerformanceCurvesButton         matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        FrictionWindageLossEditFieldLabel  matlab.ui.control.Label
        FrictionWindageLossEditField    matlab.ui.control.NumericEditField
        CoreLossEditFieldLabel          matlab.ui.control.Label
        CoreLossEditField               matlab.ui.control.NumericEditField
        OtherLossesEditFieldLabel       matlab.ui.control.Label
        OtherLossesEditField            matlab.ui.control.NumericEditField
        WLabel                          matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        WLabel_3                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        R1;
        X1;
        R2;
        X2;
        XM;
        
        AV;
        F;
        P;
        
        pfw;
        pcore;
        pmisc;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: PerformanceCurvesButton
        function PerformanceCurvesButtonPushed(app, event)
             r1 = app.StatorResistanceEditField.Value;                 % Stator resistance
            x1 = app.StatorReactanceEditField.Value;                  % Stator reactance
            r2 = app.RotorResistanceEditField.Value;                  % Rotor resistance
            x2 = app.RotorReactanceEditField.Value;                   % Rotor reactance
            xm = app.MagnetizingBranchReactanceEditField.Value;    % Magnetization branch reactance
            v_phase = app.AppliedLineVoltageEditField.Value / sqrt(3);    % Phase voltage
            f= app.FrequencyEditField.Value;
            Poles= app.NumberofPolesEditField.Value;
            
            p_fw = app.FrictionWindageLossEditField.Value;
            p_core = app.CoreLossEditField.Value;
            p_misc = app.OtherLossesEditField.Value;
            
            n_sync = 120*f/Poles;              % Synchronous speed (r/min)
            w_sync = n_sync*2*pi/60;             % Synchronous speed (rad/s)
            
            % Calculate the Thevenin voltage and impedance from Equations
            % 7-41a and 7-43.
            v_th = v_phase * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            z_th = ((1i*xm) * (r1 + 1i*x1)) / (r1 + 1i*(x1 + xm));
            r_th = real(z_th);
            x_th = imag(z_th);
            
            Tstart=(3*v_th^2*r2)/(w_sync*((r_th+r2)^2+(x_th+x2)^2));     % Caculating starting torque of motor
            Smax=r2/sqrt(r_th^2+(x_th+x2)^2);                            % Slip for maximum torque
            Tmax=(3*v_th^2)/(2*w_sync*(r_th+sqrt(r_th^2+(x_th+x2)^2)));  % Calulating maximum torque
            nm_max=(1-Smax)*n_sync;                                      % rotor speed for maximum torque


             app.TheveninVoltageEditField.Value = v_th;
             app.TheveninResistanceEditField.Value = r_th;
             app.TheveninReactanceEditField.Value = x_th;
             
             app.SynchronousSpeedEditField.Value = n_sync;
             app.StartingTorqueEditField.Value = Tstart;
             app.MaximumTorqueEditField.Value = Tmax  ;
             app.SlipforMaxTorqueEditField.Value = Smax;


            app.R1 = r1;
            app.X1 = x1;
            app.R2 = r2;
            app.X2 = x2;
            app.XM = xm ;
            
            app.AV = v_phase;
            app.F  = f  ;
            app.P  = Poles;
            
            app.pfw = p_fw;
            app.pcore = p_core;
            app.pmisc = p_misc;   
            
            IM_PC(app);
        end

        % Button pushed function: ObtainCharacteristicsButton
        function ObtainCharacteristicsButtonPushed(app, event)
            r1 = app.StatorResistanceEditField.Value;                 % Stator resistance
            x1 = app.StatorReactanceEditField.Value;                  % Stator reactance
            r2 = app.RotorResistanceEditField.Value;                  % Rotor resistance
            x2 = app.RotorReactanceEditField.Value;                   % Rotor reactance
            xm = app.MagnetizingBranchReactanceEditField.Value;    % Magnetization branch reactance
            v_phase = app.AppliedLineVoltageEditField.Value / sqrt(3);    % Phase voltage
            f= app.FrequencyEditField.Value;
            Poles= app.NumberofPolesEditField.Value;
            
            p_fw = app.FrictionWindageLossEditField.Value;
            p_core = app.CoreLossEditField.Value;
            p_misc = app.OtherLossesEditField.Value;
            
            n_sync = 120*f/Poles;              % Synchronous speed (r/min)
            w_sync = n_sync*2*pi/60;             % Synchronous speed (rad/s)
            
            % Calculate the Thevenin voltage and impedance from Equations
            % 7-41a and 7-43.
            v_th = v_phase * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            z_th = ((1i*xm) * (r1 + 1i*x1)) / (r1 + 1i*(x1 + xm));
            r_th = real(z_th);
            x_th = imag(z_th);
            
            Tstart=(3*v_th^2*r2)/(w_sync*((r_th+r2)^2+(x_th+x2)^2));     % Caculating starting torque of motor
            Smax=r2/sqrt(r_th^2+(x_th+x2)^2);                            % Slip for maximum torque
            Tmax=(3*v_th^2)/(2*w_sync*(r_th+sqrt(r_th^2+(x_th+x2)^2)));  % Calulating maximum torque
            nm_max=(1-Smax)*n_sync;                                      % rotor speed for maximum torque


             app.TheveninVoltageEditField.Value = v_th;
             app.TheveninResistanceEditField.Value = r_th;
             app.TheveninReactanceEditField.Value = x_th;
             
             app.SynchronousSpeedEditField.Value = n_sync;
             app.StartingTorqueEditField.Value = Tstart;
             app.MaximumTorqueEditField.Value = Tmax  ;
             app.SlipforMaxTorqueEditField.Value = Smax;


            app.R1 = r1;
            app.X1 = x1;
            app.R2 = r2;
            app.X2 = x2;
            app.XM = xm ;
            
            app.AV = v_phase;
            app.F  = f  ;
            app.P  = Poles;
            
            app.pfw = p_fw;
            app.pcore = p_core;
            app.pmisc = p_misc;   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Performance Analysis'};
            app.StatorResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.StatorResistanceEditFieldLabel.Text = 'Stator Resistance';
            app.StatorResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StatorResistanceEditField.Value = 0.243;
            app.StatorReactanceEditFieldLabel = uilabel(app.UIFigure);
            app.StatorReactanceEditFieldLabel.Text = 'Stator Reactance';
            app.StatorReactanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StatorReactanceEditField.Value = 0.67;
            app.RotorResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.RotorResistanceEditFieldLabel.Text = 'Rotor Resistance';
            app.RotorResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotorResistanceEditField.Value = 0.151;
            app.RotorReactanceEditFieldLabel = uilabel(app.UIFigure);
            app.RotorReactanceEditFieldLabel.Text = 'Rotor Reactance';
            app.RotorReactanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotorReactanceEditField.Value = 0.67;
            app.MagnetizingBranchReactanceLabel = uilabel(app.UIFigure);
            app.MagnetizingBranchReactanceLabel.Text = 'Magnetizing Branch Reactance';
            app.MagnetizingBranchReactanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnetizingBranchReactanceEditField.Value = 14.03;
            app.AppliedLineVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.AppliedLineVoltageEditFieldLabel.Text = 'Applied Line Voltage';
            app.AppliedLineVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.AppliedLineVoltageEditField.Value = 208;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 60;
            app.NumberofPolesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPolesEditFieldLabel.Text = 'Number of Poles';
            app.NumberofPolesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPolesEditField.Value = 4;
            app.ohmLabel = uilabel(app.UIFigure);
            app.ohmLabel.Text = 'ohm';
            app.ohmLabel_2 = uilabel(app.UIFigure);
            app.ohmLabel_2.Text = 'ohm';
            app.ohmLabel_3 = uilabel(app.UIFigure);
            app.ohmLabel_3.Text = 'ohm';
            app.ohmLabel_4 = uilabel(app.UIFigure);
            app.ohmLabel_4.Text = 'ohm';
            app.ohmLabel_5 = uilabel(app.UIFigure);
            app.ohmLabel_5.Text = 'ohm';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.TheveninVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.TheveninVoltageEditFieldLabel.Text = 'Thevenin Voltage';
            app.TheveninVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.TheveninResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.TheveninResistanceEditFieldLabel.Text = 'Thevenin  Resistance';
            app.TheveninResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.TheveninReactanceEditFieldLabel = uilabel(app.UIFigure);
            app.TheveninReactanceEditFieldLabel.Text = 'Thevenin Reactance';
            app.TheveninReactanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SynchronousSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SynchronousSpeedEditFieldLabel.Text = 'Synchronous Speed';
            app.SynchronousSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.StartingTorqueEditFieldLabel.Text = 'Starting Torque';
            app.StartingTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumTorqueEditFieldLabel.Text = 'Maximum Torque';
            app.MaximumTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlipforMaxTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.SlipforMaxTorqueEditFieldLabel.Text = 'Slip for Max Torque';
            app.SlipforMaxTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObtainCharacteristicsButton = uibutton(app.UIFigure, 'push');
            app.ObtainCharacteristicsButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainCharacteristicsButtonPushed, true);
            app.ObtainCharacteristicsButton.Text = 'Obtain Characteristics';
            app.PerformanceCurvesButton = uibutton(app.UIFigure, 'push');
            app.PerformanceCurvesButton.ButtonPushedFcn = createCallbackFcn(app, @PerformanceCurvesButtonPushed, true);
            app.PerformanceCurvesButton.Text = 'Performance Curves';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.FrictionWindageLossEditFieldLabel = uilabel(app.UIFigure);
            app.FrictionWindageLossEditFieldLabel.Text = 'Friction & Windage Loss';
            app.FrictionWindageLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoreLossEditFieldLabel = uilabel(app.UIFigure);
            app.CoreLossEditFieldLabel.Text = 'Core Loss';
            app.CoreLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.OtherLossesEditFieldLabel = uilabel(app.UIFigure);
            app.OtherLossesEditFieldLabel.Text = 'Other Losses';
            app.OtherLossesEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.WLabel_2 = uilabel(app.UIFigure);
            app.WLabel_2.Text = 'W';
            app.WLabel_3 = uilabel(app.UIFigure);
            app.WLabel_3.Text = 'W';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMchar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end