classdef MagnetizingCurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GenerateMagnetizationCurveButton  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        ApparentPowerKVAEditFieldLabel  matlab.ui.control.Label
        ApparentPowerKVAEditField       matlab.ui.control.NumericEditField
        MaximumVoltageEditFieldLabel    matlab.ui.control.Label
        MaximumVoltageEditField         matlab.ui.control.NumericEditField
        NumberofPrimaryTurnsEditFieldLabel  matlab.ui.control.Label
        NumberofPrimaryTurnsEditField   matlab.ui.control.NumericEditField
        TextArea_3                      matlab.ui.control.TextArea
        Frequency1EditFieldLabel        matlab.ui.control.Label
        Frequency1EditField             matlab.ui.control.NumericEditField
        Frequency2EditFieldLabel        matlab.ui.control.Label
        Frequency2EditField             matlab.ui.control.NumericEditField
        CompareButton                   matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        CompareButton_2                 matlab.ui.control.Button
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        Vmax1EditFieldLabel             matlab.ui.control.Label
        Vmax1EditField                  matlab.ui.control.NumericEditField
        Vmax2EditFieldLabel             matlab.ui.control.Label
        Vmax2EditField                  matlab.ui.control.NumericEditField
        ExitButton                      matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateMagnetizationCurveButton
        function GenerateMagnetizationCurveButtonPushed(app, event)
            run('E:\MATLAB CODES\grabit.m')
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            S = app.FileNameEditField.Value;
            file = importdata(S);
            D = file.data;
            mmf_data = D(:,1);
            flux_data = D(:,2);
            
            % Creating Interpolated Curve 
            
            x = mmf_data;
            y = flux_data;
            n = length(x);
            b_mat = zeros(n,n);
            b_mat(:,1) = y;
            
            for j=2:n
                for i=1:(n-j+1)
                    b_mat(i,j) = (b_mat(i,j-1)-b_mat(i+1,j-1))/(x(i)-x(i+j-1));
                end
            end
            
            p = y(1);
            var = 1;
            for k=1:n-1
                var = conv(var,poly(x(k)));
                p = [zeros(1,1),p]+ b_mat(1,k+1)*var;
            end
            mmf_data = polyval(p,flux_data);                                                                                                                                                         %#ok<NASGU> 
                                                                                                                                                              mmf_data = D(:,1);
            % plot(mmf_data,flux_data,'linewidth',2);
            
            VM = app.MaximumVoltageEditField.Value;                  % Maximum voltage (V) 
            NP = app.NumberofPrimaryTurnsEditField.Value;                 % Primary turns
            AP = app.ApparentPowerKVAEditField.Value;
            
            
            % Calculate angular velocity for 60 Hz
            freq = app.Frequency1EditField.Value;                  % Freq (Hz)
            w = 2 * pi * freq;
            f_time = 2/freq;
            step = 1/(freq*500);
            % Calculate flux versus time
        time = 0:step:f_time;       % 0 to 1/30 sec
            flux = -VM/(w*NP) * cos(w .* time);
            
            % Calculate the mmf corresponding to a given flux
            % using the 's interpolation function.
            mmf = interp1(flux_data,mmf_data,flux);
            
            % Calculate the magnetization current
            im = mmf / NP;
            
            % Calculate the rms value of the current
            irms = sqrt(sum(im.^2)/length(im));
            vrms = VM/sqrt(2);
            i_fl = AP*1000/vrms;
            prcnt = irms*100/i_fl;
            %disp(['The rms current at 60 Hz is ', num2str(irms)]);
            
            % Plot the magnetization current.
            figure(1)
            subplot(2,1,1);
            plot(time,im);
            title (['Magnetization current at ' , num2str(freq), ' Hz | RMS Current ', num2str(irms),' A | ',num2str(prcnt),' % of full-load current']);
            xlabel ('\bfTime (s)');
            ylabel ('\bf\itI_{m} \rm(A)');
            lim = ceil(max(im));
            axis([0 0.04 -lim lim]);
            grid on;
            
            % Calculate angular velocity for 50 Hz
            freq2 = app.Frequency2EditField.Value ;                  % Freq (Hz)
            w = 2 * pi * freq2;
            
            % Calculate flux versus time
            f_time = 2/freq2;
            step = 1/(500*freq2);
            time = 0:step:f_time;       % 0 to 1/25 sec
            flux = -VM/(w*NP) * cos(w .* time);
            
            % Calculate the mmf corresponding to a given flux
            % using the 's interpolation function.
            mmf = interp1(flux_data,mmf_data,flux);
            
            % Calculate the magnetization current
            im = mmf / NP;
            
            % Calculate the rms value of the current
            irms = sqrt(sum(im.^2)/length(im));
            i_fl = AP*1000/vrms;
            prcnt = irms*100/i_fl;
            %disp(['The rms current at 50 Hz is ', num2str(irms)]);
            
            % Plot the magnetization current.
            subplot(2,1,2);
            plot(time,im);
            title (['Magnetization current at ' , num2str(freq2), ' Hz | RMS Current ', num2str(irms),' A | ',num2str(prcnt),' % of full-load current']);
            xlabel ('\bfTime (s)');
            ylabel ('\bf\itI_{m} \rm(A)');
            lim = ceil(max(im));
            axis([0 0.04 -lim lim]);
            grid on;

        end

        % Button pushed function: CompareButton_2
        function CompareButton_2Pushed(app, event)
            S = app.FileNameEditField.Value;
            file = importdata(S);
            D = file.data;
            mmf_data = D(:,1);
            flux_data = D(:,2);
            
            % plot(mmf_data,flux_data,'linewidth',2);
            
            VM = app.Vmax1EditField.Value;                  % Maximum voltage (V)
            NP = app.NumberofPrimaryTurnsEditField.Value;                 % Primary turns
            AP = app.ApparentPowerKVAEditField.Value;
            
            
            % Calculate angular velocity for 60 Hz
            freq = app.FrequencyEditField.Value;                  % Freq (Hz)
            w = 2 * pi * freq;
            f_time = 2/freq;
            step = 1/(freq*500);
            % Calculate flux versus time
            time = 0:step:f_time;       % 0 to 1/30 sec
            flux = -VM/(w*NP) * cos(w .* time);
            
            % Calculate the mmf corresponding to a given flux
            % using the 's interpolation function.
            mmf = interp1(flux_data,mmf_data,flux);
            
            % Calculate the magnetization current
            im = mmf / NP;
            
            % Calculate the rms value of the current
            irms = sqrt(sum(im.^2)/length(im));
            vrms = VM/sqrt(2);
            i_fl = AP*1000/vrms;
            prcnt = irms*100/i_fl;
            %disp(['The rms current at 60 Hz is ', num2str(irms)]);
            
            % Plot the magnetization current.
            figure(1)
            subplot(2,1,1);
            plot(time,im);
            title (['Magnetization current at ' , num2str(VM), ' Volts | RMS Current ', num2str(irms),' A | ',num2str(prcnt),' % of full-load current']);
            xlabel ('\bfTime (s)');
            ylabel ('\bf\itI_{m} \rm(A)');
            %lim = ceil(max(im));
            %axis([0 0.04 -lim lim]);
            grid on;
            
            % Calculate angular velocity for 50 Hz
            VM2 = app.Vmax2EditField.Value;                 % Freq (Hz)
            w = 2 * pi * freq;
            
            % Calculate flux versus time
            f_time = 2/freq;
            step = 1/(500*freq);
            time = 0:step:f_time;       % 0 to 1/25 sec
            flux = -VM2/(w*NP) * cos(w .* time);
            
            % Calculate the mmf corresponding to a given flux
            % using the 's interpolation function.
            mmf = interp1(flux_data,mmf_data,flux);
            
            % Calculate the magnetization current
            im = mmf / NP;
            imax = max(im);
            
            % Calculate the rms value of the current
            irms = sqrt(sum(im.^2)/length(im));
            vrms = VM2/sqrt(2);
            i_fl = AP*1000/vrms;
            prcnt = irms*100/i_fl;
            %disp(['The rms current at 50 Hz is ', num2str(irms)]);
            
            % Plot the magnetization current.
            subplot(2,1,2);
            plot(time,im);
            title (['Magnetization current at ' , num2str(VM2), ' Volts | RMS Current ', num2str(irms),' A | ',num2str(prcnt),' % of full-load current']);
            xlabel ('\bfTime (s)');
            ylabel ('\bf\itI_{m} \rm(A)');
            %lim = ceil(max(im));
            %axis([0 0.04 -lim lim]);
            grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerateMagnetizationCurveButton = uibutton(app.UIFigure, 'push');
            app.GenerateMagnetizationCurveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMagnetizationCurveButtonPushed, true);
            app.GenerateMagnetizationCurveButton.Text = 'Generate Magnetization Curve';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Generate Electronic Version of the Magnetization Curve from Image.'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Enter the Data File Name of the Magnetization Curve '};
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.ApparentPowerKVAEditFieldLabel = uilabel(app.UIFigure);
            app.ApparentPowerKVAEditFieldLabel.Text = 'Apparent Power (KVA)';
            app.ApparentPowerKVAEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumVoltageEditFieldLabel.Text = 'Maximum Voltage';
            app.MaximumVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPrimaryTurnsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPrimaryTurnsEditFieldLabel.Text = 'Number of Primary Turns';
            app.NumberofPrimaryTurnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Compare Magnetizing Current for Different frequencies but Same Source Voltage'};
            app.Frequency1EditFieldLabel = uilabel(app.UIFigure);
            app.Frequency1EditFieldLabel.Text = 'Frequency 1';
            app.Frequency1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Frequency2EditFieldLabel = uilabel(app.UIFigure);
            app.Frequency2EditFieldLabel.Text = 'Frequency 2';
            app.Frequency2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Compare Magnetizing Current for Different Source Voltages but Same Frequency'; ''};
            app.CompareButton_2 = uibutton(app.UIFigure, 'push');
            app.CompareButton_2.ButtonPushedFcn = createCallbackFcn(app, @CompareButton_2Pushed, true);
            app.CompareButton_2.Text = 'Compare';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vmax1EditFieldLabel = uilabel(app.UIFigure);
            app.Vmax1EditFieldLabel.Text = 'Vmax 1';
            app.Vmax1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Vmax2EditFieldLabel = uilabel(app.UIFigure);
            app.Vmax2EditFieldLabel.Text = 'Vmax 2';
            app.Vmax2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Magnetizing Current Comparison'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagnetizingCurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end