classdef OC_SC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea_5                     matlab.ui.control.TextArea
        TextArea_4                     matlab.ui.control.TextArea
        ObtainEquivalentCircuitButton  matlab.ui.control.Button
        TurnsRationEditField_4         matlab.ui.control.NumericEditField
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        KVALabel                       matlab.ui.control.Label
        SecondaryVoltsLabel            matlab.ui.control.Label
        PrimaryVoltsLabel              matlab.ui.control.Label
        TurnsRationEditFieldLabel_2    matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        RatedPowerinKVASEditField_2    matlab.ui.control.NumericEditField
        RatedPowerinKVASEditField_2Label  matlab.ui.control.Label
        TurnsRationEditField_3         matlab.ui.control.NumericEditField
        TurnsRationEditField_3Label    matlab.ui.control.Label
        P_OCWattsEditField             matlab.ui.control.NumericEditField
        P_OCWattsEditFieldLabel        matlab.ui.control.Label
        I_OCAEditField                 matlab.ui.control.NumericEditField
        I_OCAEditFieldLabel            matlab.ui.control.Label
        V_OCVEditField                 matlab.ui.control.NumericEditField
        V_OCVEditFieldLabel            matlab.ui.control.Label
        SIDEDropDown                   matlab.ui.control.DropDown
        SIDEDropDownLabel              matlab.ui.control.Label
        P_ScWattsEditField             matlab.ui.control.NumericEditField
        P_ScWattsEditFieldLabel        matlab.ui.control.Label
        I_ScAEditField                 matlab.ui.control.NumericEditField
        I_ScAEditFieldLabel            matlab.ui.control.Label
        V_ScVEditField                 matlab.ui.control.NumericEditField
        V_ScVEditFieldLabel            matlab.ui.control.Label
        SIDEDropDown_2                 matlab.ui.control.DropDown
        SIDEDropDown_2Label            matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        j_Xm1EditFieldLabel            matlab.ui.control.Label
        j_Xm1EditField                 matlab.ui.control.NumericEditField
        R_C1EditFieldLabel             matlab.ui.control.Label
        R_C1EditField                  matlab.ui.control.NumericEditField
        R_e1EditFieldLabel             matlab.ui.control.Label
        R_e1EditField                  matlab.ui.control.NumericEditField
        Image2                         matlab.ui.control.Image
        j_Xm2EditFieldLabel            matlab.ui.control.Label
        j_Xm2EditField                 matlab.ui.control.NumericEditField
        R_C2EditFieldLabel             matlab.ui.control.Label
        R_C2EditField                  matlab.ui.control.NumericEditField
        R_C2EditFieldLabel_2           matlab.ui.control.Label
        R_C2EditFieldLabel_3           matlab.ui.control.Label
        LoadPFEditFieldLabel           matlab.ui.control.Label
        LoadPFEditField                matlab.ui.control.NumericEditField
        TransformerPerformanceButton   matlab.ui.control.Button
        PFDropDownLabel                matlab.ui.control.Label
        PFDropDown                     matlab.ui.control.DropDown
        R_e1EditFieldLabel_2           matlab.ui.control.Label
        jEditFieldLabel                matlab.ui.control.Label
        jEditField                     matlab.ui.control.NumericEditField
        R_e1EditFieldLabel_3           matlab.ui.control.Label
        R_e1EditField_2                matlab.ui.control.NumericEditField
        R_e1EditFieldLabel_4           matlab.ui.control.Label
        jEditFieldLabel_2              matlab.ui.control.Label
        jEditField_2                   matlab.ui.control.NumericEditField
        LoadVoltageEditFieldLabel      matlab.ui.control.Label
        LoadVoltageEditField           matlab.ui.control.NumericEditField
        EXITButton                     matlab.ui.control.Button
        ConverttoDropDown              matlab.ui.control.DropDown
        ConverttoButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        S;
        pf;
        pf_s;
        Vs;
        RC;
        XM;
        RP;
        XP;
        r_c;
        x_m;
        r_s;
        x_s;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end

        % Drop down opening function: SIDEDropDown
        function SIDEDropDownOpening(app, event)
            
        end

        % Button pushed function: ObtainEquivalentCircuitButton
        function ObtainEquivalentCircuitButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField_2.Value ; % Apparent Power
            RHV = app.TurnsRationEditField_3.Value ; % Rated high voltage
            LHV = app.TurnsRationEditField_4.Value ; % Rated low voltage
            Side1 = app.SIDEDropDown.Value;
            Side2 = app.SIDEDropDown_2.Value;
            
            poc = app.P_OCWattsEditField.Value; % Open circuit power
            ioc = app.I_OCAEditField.Value; % Open circuit current
            voc = app.V_OCVEditField.Value; % Open circuit voltage
            
            psc = app.P_ScWattsEditField.Value; % Short circuit power
            isc = app.I_ScAEditField.Value; %Short circuit current
            vsc = app.V_ScVEditField.Value; %Short circuit voltage
            
            a = RHV/LHV; % Turns ratio
            
            pf1 = poc / (voc*ioc);
            theta1 = acosd(pf1);
            ye = ioc/voc;
            rc = 1/(ye*pf1);
            xm = 1/(ye*sind(theta1));
            
            pf2 = psc/(vsc*isc);
            theta2 = acosd(pf2);
            zse = vsc/isc;
            req = zse*pf2;
            xeq = zse*sind(theta2);
            
            if strcmp(Side1,'LV') && strcmp(Side2,'HV')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C1EditField.Value = a^2 * rc ;
                app.j_Xm1EditField.Value = a^2 * xm;
                app.R_e1EditField_2.Value = req/a^2;
                app.jEditField_2.Value = xeq/a^2;
                
            end
            if strcmp(Side1,'HV') && strcmp(Side2,'LV')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField.Value = req*a^2;
                app.jEditField.Value = xeq*a^2;
                
            end
            
            if strcmp(Side1,'HV') && strcmp(Side2,'HV')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField_2.Value = req/a^2;
                app.jEditField_2.Value = xeq/a^2;
                
            end
            
            if strcmp(Side1,'LV') && strcmp(Side2,'LV')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C1EditField.Value = rc*a^2 ;
                app.j_Xm1EditField.Value = xm*a^2 ;
                app.R_e1EditField.Value = req*a^2;
                app.jEditField.Value = xeq*a^2;
                
            end
            
            
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField.Value;
            app.XP = app.jEditField.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField_2.Value;
            app.x_s = app.jEditField_2.Value;


        end

        % Button pushed function: TransformerPerformanceButton
        function TransformerPerformanceButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField_2.Value ; % Apparent Power
            RHV = app.TurnsRationEditField_3.Value ; % Rated high voltage
            LHV = app.TurnsRationEditField_4.Value ; % Rated low voltage
            Side1 = app.SIDEDropDown.Value;
            Side2 = app.SIDEDropDown_2.Value;
            
            poc = app.P_OCWattsEditField.Value; % Open circuit power
            ioc = app.I_OCAEditField.Value; % Open circuit current
            voc = app.V_OCVEditField.Value; % Open circuit voltage
            
            psc = app.P_ScWattsEditField.Value; % Short circuit power
            isc = app.I_ScAEditField.Value; %Short circuit current
            vsc = app.V_ScVEditField.Value; %Short circuit voltage
            
            a = RHV/LHV; % Turns ratio
            
            pf1 = poc / (voc*ioc);
            theta1 = acosd(pf1);
            ye = ioc/voc;
            rc = 1/(ye*pf1);
            xm = 1/(ye*sind(theta1));
            
            pf2 = psc/(vsc*isc);
            theta2 = acosd(pf2);
            zse = vsc/isc;
            req = zse*pf2;
            xeq = zse*sind(theta2);
            
            if strcmp(Side1,'LV') && strcmp(Side2,'HV')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C1EditField.Value = a^2 * rc ;
                app.j_Xm1EditField.Value = a^2 * xm;
                app.R_e1EditField_2.Value = req/a^2;
                app.jEditField_2.Value = xeq/a^2;
                
            end
            if strcmp(Side1,'HV') && strcmp(Side2,'LV')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField.Value = req*a^2;
                app.jEditField.Value = xeq*a^2;
                
            end
            
            if strcmp(Side1,'HV') && strcmp(Side2,'HV')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField_2.Value = req/a^2;
                app.jEditField_2.Value = xeq/a^2;
                
            end
            
            if strcmp(Side1,'LV') && strcmp(Side2,'LV')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C1EditField.Value = rc*a^2 ;
                app.j_Xm1EditField.Value = xm*a^2 ;
                app.R_e1EditField.Value = req*a^2;
                app.jEditField.Value = xeq*a^2;
                
            end          


            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField.Value;
            app.XP = app.jEditField.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField_2.Value;
            app.x_s = app.jEditField_2.Value;

            TransformerPerformance1(app);
        end

        % Button pushed function: ConverttoButton
        function ConverttoButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField_2.Value ; % Apparent Power
            RHV = app.TurnsRationEditField_3.Value ; % Rated high voltage
            LHV = app.TurnsRationEditField_4.Value ; % Rated low voltage
            Side1 = app.SIDEDropDown.Value;
            Side2 = app.SIDEDropDown_2.Value;
            
            poc = app.P_OCWattsEditField.Value; % Open circuit power
            ioc = app.I_OCAEditField.Value; % Open circuit current
            voc = app.V_OCVEditField.Value; % Open circuit voltage
            
            psc = app.P_ScWattsEditField.Value; % Short circuit power
            isc = app.I_ScAEditField.Value; %Short circuit current
            vsc = app.V_ScVEditField.Value; %Short circuit voltage
            
            a = RHV/LHV; % Turns ratio
            
            pf1 = poc / (voc*ioc);
            theta1 = acosd(pf1);
            ye = ioc/voc;
            rc = 1/(ye*pf1);
            xm = 1/(ye*sind(theta1));
            
            pf2 = psc/(vsc*isc);
            theta2 = acosd(pf2);
            zse = vsc/isc;
            req = zse*pf2;
            xeq = zse*sind(theta2);
            
            if strcmp(Side1,'LV') && strcmp(Side2,'HV')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C1EditField.Value = a^2 * rc ;
                app.j_Xm1EditField.Value = a^2 * xm;
                app.R_e1EditField_2.Value = req/a^2;
                app.jEditField_2.Value = xeq/a^2;
                
            end
            if strcmp(Side1,'HV') && strcmp(Side2,'LV')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField.Value = req*a^2;
                app.jEditField.Value = xeq*a^2;
                
            end
            
            if strcmp(Side1,'HV') && strcmp(Side2,'HV')
                app.R_C1EditField.Value = rc;
                app.j_Xm1EditField.Value = xm;
                app.R_e1EditField.Value = req;
                app.jEditField.Value = xeq;
                
                app.R_C2EditField.Value = rc/a^2 ;
                app.j_Xm2EditField.Value = xm/a^2 ;
                app.R_e1EditField_2.Value = req/a^2;
                app.jEditField_2.Value = xeq/a^2;
                
            end
            
            if strcmp(Side1,'LV') && strcmp(Side2,'LV')
                app.R_C2EditField.Value = rc;
                app.j_Xm2EditField.Value = xm;
                app.R_e1EditField_2.Value = req;
                app.jEditField_2.Value = xeq;
                
                app.R_C1EditField.Value = rc*a^2 ;
                app.j_Xm1EditField.Value = xm*a^2 ;
                app.R_e1EditField.Value = req*a^2;
                app.jEditField.Value = xeq*a^2;
                
            end   
            
            
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField.Value;
            app.XP = app.jEditField.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField_2.Value;
            app.x_s = app.jEditField_2.Value;
            
            if strcmp(app.ConverttoDropDown.Value,'Step-up Auto Transformer')
                AT_OSCU(app);
            end
            if strcmp(app.ConverttoDropDown.Value,'Step-down Auto Transformer')
                AT_OSCD(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Equivalent Circuit referred to Secondary'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Equivalent Circuit referred to Primary'};
            app.ObtainEquivalentCircuitButton = uibutton(app.UIFigure, 'push');
            app.ObtainEquivalentCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainEquivalentCircuitButtonPushed, true);
            app.ObtainEquivalentCircuitButton.Text = 'Obtain Equivalent Circuit';
            app.TurnsRationEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'SHORT CIRCUIT TEST'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'OPEN CIRCUIT TEST'};
            app.KVALabel = uilabel(app.UIFigure);
            app.KVALabel.Text = 'KVA';
            app.SecondaryVoltsLabel = uilabel(app.UIFigure);
            app.SecondaryVoltsLabel.Text = 'Secondary Volts';
            app.PrimaryVoltsLabel = uilabel(app.UIFigure);
            app.PrimaryVoltsLabel.Text = 'Primary Volts';
            app.TurnsRationEditFieldLabel_2 = uilabel(app.UIFigure);
            app.TurnsRationEditFieldLabel_2.Text = ':';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'TRANSFORMER  OPEN  CIRCUIT  &  SHORT  CIRCUIT  TEST'};
            app.RatedPowerinKVASEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RatedPowerinKVASEditField_2Label = uilabel(app.UIFigure);
            app.RatedPowerinKVASEditField_2Label.Text = 'Rated Power in KVA. S';
            app.TurnsRationEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.TurnsRationEditField_3Label = uilabel(app.UIFigure);
            app.TurnsRationEditField_3Label.Text = 'Turns Ration';
            app.P_OCWattsEditField = uieditfield(app.UIFigure, 'numeric');
            app.P_OCWattsEditFieldLabel = uilabel(app.UIFigure);
            app.P_OCWattsEditFieldLabel.Text = 'P_OC(Watts)';
            app.I_OCAEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_OCAEditFieldLabel = uilabel(app.UIFigure);
            app.I_OCAEditFieldLabel.Text = 'I_OC(A)';
            app.V_OCVEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_OCVEditFieldLabel = uilabel(app.UIFigure);
            app.V_OCVEditFieldLabel.Text = 'V_OC(V)';
            app.SIDEDropDown = uidropdown(app.UIFigure);
            app.SIDEDropDown.Items = {'LV', 'HV'};
            app.SIDEDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SIDEDropDownOpening, true);
            app.SIDEDropDown.Value = 'LV';
            app.SIDEDropDownLabel = uilabel(app.UIFigure);
            app.SIDEDropDownLabel.Text = 'SIDE';
            app.P_ScWattsEditField = uieditfield(app.UIFigure, 'numeric');
            app.P_ScWattsEditFieldLabel = uilabel(app.UIFigure);
            app.P_ScWattsEditFieldLabel.Text = 'P_Sc(Watts)';
            app.I_ScAEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_ScAEditFieldLabel = uilabel(app.UIFigure);
            app.I_ScAEditFieldLabel.Text = 'I_Sc(A)';
            app.V_ScVEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_ScVEditField.Value = 47;
            app.V_ScVEditFieldLabel = uilabel(app.UIFigure);
            app.V_ScVEditFieldLabel.Text = 'V_Sc(V)';
            app.SIDEDropDown_2 = uidropdown(app.UIFigure);
            app.SIDEDropDown_2.Items = {'LV', 'HV'};
            app.SIDEDropDown_2.Value = 'HV';
            app.SIDEDropDown_2Label = uilabel(app.UIFigure);
            app.SIDEDropDown_2Label.Text = 'SIDE';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'eq_cir1.PNG';
            app.j_Xm1EditFieldLabel = uilabel(app.UIFigure);
            app.j_Xm1EditFieldLabel.Text = 'j_Xm1';
            app.j_Xm1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_C1EditFieldLabel = uilabel(app.UIFigure);
            app.R_C1EditFieldLabel.Text = 'R_C1';
            app.R_C1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_e1EditFieldLabel = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel.Text = 'R_e1';
            app.R_e1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'eq_cirf.PNG';
            app.j_Xm2EditFieldLabel = uilabel(app.UIFigure);
            app.j_Xm2EditFieldLabel.Text = 'j_Xm2';
            app.j_Xm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_C2EditFieldLabel = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel.Text = 'R_C2';
            app.R_C2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_C2EditFieldLabel_2 = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel_2.Text = 'Load';
            app.R_C2EditFieldLabel_3 = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel_3.Text = 'Load';
            app.LoadPFEditFieldLabel = uilabel(app.UIFigure);
            app.LoadPFEditFieldLabel.Text = 'Load PF';
            app.LoadPFEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransformerPerformanceButton = uibutton(app.UIFigure, 'push');
            app.TransformerPerformanceButton.ButtonPushedFcn = createCallbackFcn(app, @TransformerPerformanceButtonPushed, true);
            app.TransformerPerformanceButton.Text = 'Transformer Performance';
            app.PFDropDownLabel = uilabel(app.UIFigure);
            app.PFDropDownLabel.Text = 'PF';
            app.PFDropDown = uidropdown(app.UIFigure);
            app.PFDropDown.Items = {'Unity', 'Leading', 'Lagging'};
            app.PFDropDown.Value = 'Leading';
            app.R_e1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel_2.Text = 'X_e1';
            app.jEditFieldLabel = uilabel(app.UIFigure);
            app.jEditFieldLabel.Text = 'j';
            app.jEditField = uieditfield(app.UIFigure, 'numeric');
            app.R_e1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel_3.Text = 'R_e2';
            app.R_e1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.R_e1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel_4.Text = 'X_e2';
            app.jEditFieldLabel_2 = uilabel(app.UIFigure);
            app.jEditFieldLabel_2.Text = 'j';
            app.jEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LoadVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.LoadVoltageEditFieldLabel.Text = 'Load Voltage';
            app.LoadVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.ConverttoDropDown = uidropdown(app.UIFigure);
            app.ConverttoDropDown.Items = {'Step-up Auto Transformer', 'Step-down Auto Transformer'};
            app.ConverttoDropDown.Value = 'Step-up Auto Transformer';
            app.ConverttoButton = uibutton(app.UIFigure, 'push');
            app.ConverttoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoButtonPushed, true);
            app.ConverttoButton.Text = 'Convert to';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OC_SC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end