classdef PSImp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TurnsRationEditField            matlab.ui.control.NumericEditField
        KVALabel                        matlab.ui.control.Label
        SecondaryVoltsLabel             matlab.ui.control.Label
        PrimaryVoltsLabel               matlab.ui.control.Label
        TurnsRationEditFieldLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        RatedPowerinKVASEditFieldLabel  matlab.ui.control.Label
        RatedPowerinKVASEditField       matlab.ui.control.NumericEditField
        TurnsRationEditField_2Label     matlab.ui.control.Label
        TurnsRationEditField_2          matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        R_1ohmEditFieldLabel            matlab.ui.control.Label
        R_1ohmEditField                 matlab.ui.control.NumericEditField
        X_1ohmEditFieldLabel            matlab.ui.control.Label
        X_1ohmEditField                 matlab.ui.control.NumericEditField
        X_mohmEditFieldLabel            matlab.ui.control.Label
        X_mohmEditField                 matlab.ui.control.NumericEditField
        R_CohmEditFieldLabel            matlab.ui.control.Label
        R_CohmEditField                 matlab.ui.control.NumericEditField
        R_2ohmEditFieldLabel            matlab.ui.control.Label
        R_2ohmEditField                 matlab.ui.control.NumericEditField
        X_2ohmEditFieldLabel            matlab.ui.control.Label
        X_2ohmEditField                 matlab.ui.control.NumericEditField
        R_CohmEditFieldLabel_2          matlab.ui.control.Label
        ObtainEquivalentCircuitButton   matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        Image_2                         matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        R_C2EditFieldLabel              matlab.ui.control.Label
        R_C2EditFieldLabel_2            matlab.ui.control.Label
        TransformerPerformanceButton    matlab.ui.control.Button
        R_e1EditFieldLabel              matlab.ui.control.Label
        R_e1EditFieldLabel_2            matlab.ui.control.Label
        R_e1EditField                   matlab.ui.control.NumericEditField
        R_e1EditFieldLabel_3            matlab.ui.control.Label
        jEditFieldLabel                 matlab.ui.control.Label
        jEditField                      matlab.ui.control.NumericEditField
        j_Xm1EditFieldLabel             matlab.ui.control.Label
        j_Xm1EditField                  matlab.ui.control.NumericEditField
        R_C1EditFieldLabel              matlab.ui.control.Label
        R_C1EditField                   matlab.ui.control.NumericEditField
        R_e1EditField_2Label            matlab.ui.control.Label
        R_e1EditField_2                 matlab.ui.control.NumericEditField
        j_Xm2EditFieldLabel             matlab.ui.control.Label
        j_Xm2EditField                  matlab.ui.control.NumericEditField
        R_C2EditFieldLabel_3            matlab.ui.control.Label
        R_C2EditField                   matlab.ui.control.NumericEditField
        LoadPFEditFieldLabel            matlab.ui.control.Label
        LoadPFEditField                 matlab.ui.control.NumericEditField
        PFDropDownLabel                 matlab.ui.control.Label
        PFDropDown                      matlab.ui.control.DropDown
        jEditField_2Label               matlab.ui.control.Label
        jEditField_2                    matlab.ui.control.NumericEditField
        LoadVoltageEditFieldLabel       matlab.ui.control.Label
        LoadVoltageEditField            matlab.ui.control.NumericEditField
        ConverttoDropDown               matlab.ui.control.DropDown
        ConverttoButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        S;
        pf;
        pf_s;
        Vs;
        RC;
        XM;
        RP;
        XP;
        r_c;
        x_m;
        r_s;
        x_s;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function EXITButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed2(app, event)
            app.delete
        end

        % Button pushed function: ObtainEquivalentCircuitButton
        function ObtainEquivalentCircuitButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField.Value;
            RHV = app.TurnsRationEditField_2.Value;
            LHV = app.TurnsRationEditField.Value;
            
            a = RHV/LHV;
            
            R_P = app.R_1ohmEditField.Value;
            X_P = app.X_1ohmEditField.Value;
            R_C = app.R_CohmEditField.Value;
            X_M = app.X_mohmEditField.Value;
            
            R_S = app.R_2ohmEditField.Value;
            X_S = app.X_2ohmEditField.Value;
            
            app.R_C1EditField.Value = R_C;
            app.j_Xm1EditField.Value = X_M;
            app.R_e1EditField_2.Value = R_P + R_S*a^2;
            app.jEditField_2.Value = X_P + X_S*a^2 ;
            
            app.R_C2EditField.Value = R_C / a^2 ;
            app.j_Xm2EditField.Value = X_M / a^2 ;
            app.R_e1EditField.Value = R_S + R_P/a^2;
            app.jEditField.Value = X_S + X_P/a^2;
            
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField_2.Value;
            app.XP = app.jEditField_2.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField.Value;
            app.x_s = app.jEditField.Value;
            
        end

        % Button pushed function: TransformerPerformanceButton
        function TransformerPerformanceButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField.Value;
            RHV = app.TurnsRationEditField_2.Value;
            LHV = app.TurnsRationEditField.Value;
            
            a = RHV/LHV;
            
            R_P = app.R_1ohmEditField.Value;
            X_P = app.X_1ohmEditField.Value;
            R_C = app.R_CohmEditField.Value;
            X_M = app.X_mohmEditField.Value;
            
            R_S = app.R_2ohmEditField.Value;
            X_S = app.X_2ohmEditField.Value;
            
            app.R_C1EditField.Value = R_C;
            app.j_Xm1EditField.Value = X_M;
            app.R_e1EditField_2.Value = R_P + R_S*a^2;
            app.jEditField_2.Value = X_P + X_S*a^2 ;
            
            app.R_C2EditField.Value = R_C / a^2 ;
            app.j_Xm2EditField.Value = X_M / a^2 ;
            app.R_e1EditField.Value = R_S + R_P/a^2;
            app.jEditField.Value = X_S + X_P/a^2;
            
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField_2.Value;
            app.XP = app.jEditField_2.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField.Value;
            app.x_s = app.jEditField.Value;
            
            TransformerPerformance3(app);
        end

        % Button pushed function: ConverttoButton
        function ConverttoButtonPushed(app, event)
            AP = app.RatedPowerinKVASEditField.Value;
            RHV = app.TurnsRationEditField_2.Value;
            LHV = app.TurnsRationEditField.Value;
            
            a = RHV/LHV;
            
            R_P = app.R_1ohmEditField.Value;
            X_P = app.X_1ohmEditField.Value;
            R_C = app.R_CohmEditField.Value;
            X_M = app.X_mohmEditField.Value;
            
            R_S = app.R_2ohmEditField.Value;
            X_S = app.X_2ohmEditField.Value;
            
            app.R_C1EditField.Value = R_C;
            app.j_Xm1EditField.Value = X_M;
            app.R_e1EditField_2.Value = R_P + R_S*a^2;
            app.jEditField_2.Value = X_P + X_S*a^2 ;
            
            app.R_C2EditField.Value = R_C / a^2 ;
            app.j_Xm2EditField.Value = X_M / a^2 ;
            app.R_e1EditField.Value = R_S + R_P/a^2;
            app.jEditField.Value = X_S + X_P/a^2;
            
            app.S = AP;
            app.pf = app.LoadPFEditField.Value;
            app.pf_s = app.PFDropDown.Value;
            app.Vs = app.LoadVoltageEditField.Value;
            app.RC = app.R_C1EditField.Value;
            app.XM = app.j_Xm1EditField.Value;
            app.RP = app.R_e1EditField_2.Value;
            app.XP = app.jEditField_2.Value;
            app.r_c = app.R_C2EditField.Value;
            app.x_m = app.j_Xm2EditField.Value;
            app.r_s = app.R_e1EditField.Value;
            app.x_s = app.jEditField.Value;


            if strcmp(app.ConverttoDropDown.Value,'Step-up Auto Transformer')
                AT_PSIU(app);
            end
            if strcmp(app.ConverttoDropDown.Value,'Step-down Auto Transformer')
                AT_PSID(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TurnsRationEditField = uieditfield(app.UIFigure, 'numeric');
            app.KVALabel = uilabel(app.UIFigure);
            app.KVALabel.Text = 'KVA';
            app.SecondaryVoltsLabel = uilabel(app.UIFigure);
            app.SecondaryVoltsLabel.Text = 'Secondary Volts';
            app.PrimaryVoltsLabel = uilabel(app.UIFigure);
            app.PrimaryVoltsLabel.Text = 'Primary Volts';
            app.TurnsRationEditFieldLabel = uilabel(app.UIFigure);
            app.TurnsRationEditFieldLabel.Text = ':';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'TRANSFORMER  EQUIVALENT CIRCUIT & PERFORMANCE'};
            app.RatedPowerinKVASEditFieldLabel = uilabel(app.UIFigure);
            app.RatedPowerinKVASEditFieldLabel.Text = 'Rated Power in KVA. S';
            app.RatedPowerinKVASEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurnsRationEditField_2Label = uilabel(app.UIFigure);
            app.TurnsRationEditField_2Label.Text = 'Turns Ration';
            app.TurnsRationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Transformer-02.PNG';
            app.R_1ohmEditFieldLabel = uilabel(app.UIFigure);
            app.R_1ohmEditFieldLabel.Text = 'R_1(ohm)';
            app.R_1ohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_1ohmEditFieldLabel = uilabel(app.UIFigure);
            app.X_1ohmEditFieldLabel.Text = 'X_1(ohm)';
            app.X_1ohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_mohmEditFieldLabel = uilabel(app.UIFigure);
            app.X_mohmEditFieldLabel.Text = 'X_m (ohm)';
            app.X_mohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.R_CohmEditFieldLabel = uilabel(app.UIFigure);
            app.R_CohmEditFieldLabel.Text = 'R_C(ohm)';
            app.R_CohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.R_2ohmEditFieldLabel = uilabel(app.UIFigure);
            app.R_2ohmEditFieldLabel.Text = 'R_2 (ohm)';
            app.R_2ohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_2ohmEditFieldLabel = uilabel(app.UIFigure);
            app.X_2ohmEditFieldLabel.Text = 'X_2(ohm)';
            app.X_2ohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.R_CohmEditFieldLabel_2 = uilabel(app.UIFigure);
            app.R_CohmEditFieldLabel_2.Text = 'Load';
            app.ObtainEquivalentCircuitButton = uibutton(app.UIFigure, 'push');
            app.ObtainEquivalentCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainEquivalentCircuitButtonPushed, true);
            app.ObtainEquivalentCircuitButton.Text = 'Obtain Equivalent Circuit';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed2, true);
            app.EXITButton.Text = 'EXIT';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Equivalent Circuit referred to Secondary'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Equivalent Circuit referred to Primary'};
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'eq_cir1.PNG';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'eq_cirf.PNG';
            app.R_C2EditFieldLabel = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel.Text = 'Load';
            app.R_C2EditFieldLabel_2 = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel_2.Text = 'Load';
            app.TransformerPerformanceButton = uibutton(app.UIFigure, 'push');
            app.TransformerPerformanceButton.ButtonPushedFcn = createCallbackFcn(app, @TransformerPerformanceButtonPushed, true);
            app.TransformerPerformanceButton.Text = 'Transformer Performance';
            app.R_e1EditFieldLabel = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel.Text = 'X_e1';
            app.R_e1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel_2.Text = 'R_e2';
            app.R_e1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_e1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.R_e1EditFieldLabel_3.Text = 'X_e2';
            app.jEditFieldLabel = uilabel(app.UIFigure);
            app.jEditFieldLabel.Text = 'j';
            app.jEditField = uieditfield(app.UIFigure, 'numeric');
            app.j_Xm1EditFieldLabel = uilabel(app.UIFigure);
            app.j_Xm1EditFieldLabel.Text = 'j_Xm1';
            app.j_Xm1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_C1EditFieldLabel = uilabel(app.UIFigure);
            app.R_C1EditFieldLabel.Text = 'R_C1';
            app.R_C1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_e1EditField_2Label = uilabel(app.UIFigure);
            app.R_e1EditField_2Label.Text = 'R_e1';
            app.R_e1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.j_Xm2EditFieldLabel = uilabel(app.UIFigure);
            app.j_Xm2EditFieldLabel.Text = 'j_Xm2';
            app.j_Xm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_C2EditFieldLabel_3 = uilabel(app.UIFigure);
            app.R_C2EditFieldLabel_3.Text = 'R_C2';
            app.R_C2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadPFEditFieldLabel = uilabel(app.UIFigure);
            app.LoadPFEditFieldLabel.Text = 'Load PF';
            app.LoadPFEditField = uieditfield(app.UIFigure, 'numeric');
            app.PFDropDownLabel = uilabel(app.UIFigure);
            app.PFDropDownLabel.Text = 'PF';
            app.PFDropDown = uidropdown(app.UIFigure);
            app.PFDropDown.Items = {'Unity', 'Leading', 'Lagging'};
            app.PFDropDown.Value = 'Leading';
            app.jEditField_2Label = uilabel(app.UIFigure);
            app.jEditField_2Label.Text = 'j';
            app.jEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LoadVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.LoadVoltageEditFieldLabel.Text = 'Load Voltage';
            app.LoadVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConverttoDropDown = uidropdown(app.UIFigure);
            app.ConverttoDropDown.Items = {'Step-up Auto Transformer', 'Step-down Auto Transformer'};
            app.ConverttoDropDown.Value = 'Step-up Auto Transformer';
            app.ConverttoButton = uibutton(app.UIFigure, 'push');
            app.ConverttoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoButtonPushed, true);
            app.ConverttoButton.Text = 'Convert to';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSImp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end