classdef SpeedCtrl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        Button                          matlab.ui.control.Button
        AppliedLineVoltageEditFieldLabel  matlab.ui.control.Label
        AppliedLineVoltageEditField     matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        PolesEditFieldLabel             matlab.ui.control.Label
        PolesEditField                  matlab.ui.control.NumericEditField
        R1EditFieldLabel                matlab.ui.control.Label
        R1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        XmEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.NumericEditField
        AnalyzeButton                   matlab.ui.control.Button
        R1EditField_2Label              matlab.ui.control.Label
        R1EditField_2                   matlab.ui.control.NumericEditField
        X1EditField_2Label              matlab.ui.control.Label
        X1EditField_2                   matlab.ui.control.NumericEditField
        X2EditField_2Label              matlab.ui.control.Label
        X2EditField_2                   matlab.ui.control.NumericEditField
        XmEditField_2Label              matlab.ui.control.Label
        XmEditField_2                   matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        R2EditField                     matlab.ui.control.NumericEditField
        TestingStatorVoltagesVLabel     matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        AnalyzeButton_2                 matlab.ui.control.Button
        R1EditField_3Label              matlab.ui.control.Label
        R1EditField_3                   matlab.ui.control.NumericEditField
        X1EditField_3Label              matlab.ui.control.Label
        X1EditField_3                   matlab.ui.control.NumericEditField
        X2EditField_3Label              matlab.ui.control.Label
        X2EditField_3                   matlab.ui.control.NumericEditField
        R2EditField_2Label              matlab.ui.control.Label
        R2EditField_2                   matlab.ui.control.NumericEditField
        TestingFrequenciesHzLabel       matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        AnalyzeButton_3                 matlab.ui.control.Button
        TestingRotorResistanceLabel     matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        AnalyzeButton_4                 matlab.ui.control.Button
        R1EditField_4Label              matlab.ui.control.Label
        R1EditField_4                   matlab.ui.control.NumericEditField
        X1EditField_4Label              matlab.ui.control.Label
        X1EditField_4                   matlab.ui.control.NumericEditField
        X2EditField_4Label              matlab.ui.control.Label
        X2EditField_4                   matlab.ui.control.NumericEditField
        StartingTorqueNmEditFieldLabel  matlab.ui.control.Label
        StartingTorqueNmEditField       matlab.ui.control.NumericEditField
        R2EditField_3Label              matlab.ui.control.Label
        R2EditField_3                   matlab.ui.control.NumericEditField
        ReferenceSpeedRPMEditFieldLabel  matlab.ui.control.Label
        ReferenceSpeedRPMEditField      matlab.ui.control.NumericEditField
        R1EditField_5Label              matlab.ui.control.Label
        R1EditField_5                   matlab.ui.control.NumericEditField
        X1EditField_5Label              matlab.ui.control.Label
        X1EditField_5                   matlab.ui.control.NumericEditField
        X2EditField_5Label              matlab.ui.control.Label
        X2EditField_5                   matlab.ui.control.NumericEditField
        R2EditField_4Label              matlab.ui.control.Label
        R2EditField_4                   matlab.ui.control.NumericEditField
        RatedPowerEditFieldLabel        matlab.ui.control.Label
        RatedPowerEditField             matlab.ui.control.NumericEditField
        AnalyzeButton_5                 matlab.ui.control.Button
        XmEditField_3Label              matlab.ui.control.Label
        XmEditField_3                   matlab.ui.control.NumericEditField
        WLabel                          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            r1 = app.R1EditField.Value;                % Stator resistance
            x1 = app.X1EditField.Value;                  % Stator reactance
            x2 = app.X2EditField.Value;                   % Rotor reactance
            xm = app.XmEditField.Value;    % Magnetization branch reactance
            v_phase = app.AppliedLineVoltageEditField.Value / sqrt(3);    % Phase voltage
            f= app.FrequencyEditField.Value;
            Poles= app.PolesEditField.Value;
            r21 = app.EditField_9.Value;
            r22 = app.EditField_10.Value;
            r23 = app.EditField_11.Value;
            r24 = app.EditField_12.Value;
            
            n_sync = 120*f/Poles;              % Synchronous speed (r/min)
            w_sync = n_sync*2*pi/60;             % Synchronous speed (rad/s)
            
            % Calculate the Thevenin voltage and impedance from Equations
            % 7-41a and 7-43.
            v_th = v_phase * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            z_th = ((1i*xm) * (r1 + 1i*x1)) / (r1 + 1i*(x1 + xm));
            r_th = real(z_th);
            x_th = imag(z_th);
            r2_max = sqrt(r_th^2+(x_th+x2)^2); %when S_max=1
            r2= [r21 r22 r23 r24 r2_max];
            %disp(r2);
            
            % Now calculate the torque-speed characteristic for many
            % slips between 0 and 1.  Note that the first slip value
            % is set to 0.001 instead of exactly 0 to avoid divide-
            % by-zero problems.
            s = (0:.1:50) / 50;           % Slip
            s(1) = 0.001;
            nm = (1 - s) * n_sync;       % Mechanical speed rpm
            wm = (1-s) * w_sync;         % Mechanical speed rps
            
            
            %when r2=r2(2)
            t_ind1 = zeros(1,length(s));
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2(1) / s(ii)) / ...
                    (w_sync * ((r_th + r2(1)/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes,nm,t_ind1,'b','LineWidth',2.0);
            %axis([0 1850 0 800]);
            %xlabel('\itn_{m}','Fontweight','Bold');
            %ylabel('\tau_{ind}','Fontweight','Bold');
            %title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
            %grid on;
            hold(app.UIAxes,"on");
            
            %when r2=r2(3)
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2(2) / s(ii)) / ...
                    (w_sync * ((r_th + r2(2)/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes,nm,t_ind1,'Color','g','LineWidth',2.0);
            %axis([0 1850 0 800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
            %hold(app.UIAxes);
            
            %when r2=r2(4)
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2(3) / s(ii)) / ...
                    (w_sync * ((r_th + r2(3)/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes,nm,t_ind1,'Color','c','LineWidth',2.0);
%             %axis([0 1850 0 800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
            %hold(app.UIAxes);
            
            %when r2=r2(5)
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2(4) / s(ii)) / ...
                    (w_sync * ((r_th + r2(4)/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes,nm,t_ind1,'Color','k','LineWidth',2.0);
            %axis([0 1850 0 800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
            %hold(app.UIAxes);
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2(5) / s(ii)) / ...
                    (w_sync * ((r_th + r2(5)/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes,nm,t_ind1,'Color','r','LineWidth',2.0);
            hold(app.UIAxes,"off");
            
            legend(app.UIAxes,['r2= ', num2str(r2(1))],['r2= ', num2str(r2(2))],['r2= ', num2str(r2(3))],['r2= ', num2str(r2(4))],['r2= ', num2str(r2(5))],'Location','southwest');
        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed(app, event)
            r1 = app.R1EditField_2.Value;                % Stator resistance
            x1 = app.X1EditField_2.Value;                 % Stator reactance
            r2 = app.R2EditField.Value;                 % Rotor resistance
            x2 = app.X2EditField_2.Value;                 % Rotor reactance
            xm = app.XmEditField_2.Value;    % Magnetization branch reactance
            v_phase1 = app.EditField.Value/ sqrt(3);    % Phase voltage
            v_phase2 = app.EditField_2.Value / sqrt(3);
            v_phase3 = app.EditField_3.Value / sqrt(3);
            v_phase4 = app.EditField_4.Value / sqrt(3);
            v_phase5 = app.AppliedLineVoltageEditField.Value / sqrt(3);
            
            
            f= app.FrequencyEditField.Value;
            Poles= app.PolesEditField.Value;
            
            n_sync = 120*f/Poles;              % Synchronous speed (r/min)
            w_sync = n_sync*2*pi/60;             % Synchronous speed (rad/s)
            
            % Calculate the Thevenin voltage and impedance from Equations
            % 7-41a and 7-43.
            
            z_th = ((1i*xm) * (r1 + 1i*x1)) / (r1 + 1i*(x1 + xm));
            r_th = real(z_th);
            x_th = imag(z_th);
            
            % Now calculate the torque-speed characteristic for many
            % slips between 0 and 1.  Note that the first slip value
            % is set to 0.001 instead of exactly 0 to avoid divide-
            % by-zero problems.
            s = (0:.1:50) / 50;           % Slip
            s(1) = 0.001;
            nm = (1 - s) * n_sync;       % Mechanical speed rpm
            wm = (1-s) * w_sync;         % Mechanical speed rps
            
            % v_phase1
            
            v_th = v_phase1 * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2 / s(ii)) / ...
                    (w_sync * ((r_th + r2/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes_2,nm,t_ind1,'Color','b','LineWidth',2.0);
%             axis([0 1850 0 1800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
            hold(app.UIAxes_2,'on');
            
            % v_phase2
            v_th = v_phase2 * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2 / s(ii)) / ...
                    (w_sync * ((r_th + r2/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes_2,nm,t_ind1,'Color','g','LineWidth',2.0);
%             axis([0 1850 0 1800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
%             hold on;
            % v_phase3
            v_th = v_phase3 * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2 / s(ii)) / ...
                    (w_sync * ((r_th + r2/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes_2,nm,t_ind1,'Color','c','LineWidth',2.0);
%             axis([0 1850 0 1800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
%             hold on;
            
            % v_phase4
            v_th = v_phase4 * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2 / s(ii)) / ...
                    (w_sync * ((r_th + r2/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes_2,nm,t_ind1,'Color','k','LineWidth',2.0);
%             axis([0 1850 0 1800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
%             hold on;
            
            % v_phase5
            v_th = v_phase5 * ( xm / sqrt(r1^2 + (x1 + xm)^2) );
            
            for ii = 1:length(s)
                t_ind1(ii) = (3 * v_th^2 * r2 / s(ii)) / ...
                    (w_sync * ((r_th + r2/s(ii))^2 + (x_th + x2)^2) );
            end
            
            plot(app.UIAxes_2,nm,t_ind1,'Color','r','LineWidth',2.0);
%             axis([0 1850 0 1800]);
%             xlabel('\itn_{m}','Fontweight','Bold');
%             ylabel('\tau_{ind}','Fontweight','Bold');
%             title ('Induction Motor Torque-Speed Characteristic','Fontweight','Bold');
%             grid on;
%             hold on;
            hold(app.UIAxes_2,"off");
            v_phase1 = app.EditField.Value;    % Phase voltage
            v_phase2 = app.EditField_2.Value;
            v_phase3 = app.EditField_3.Value;
            v_phase4 = app.EditField_4.Value;
            v_phase5 = app.AppliedLineVoltageEditField.Value;
            
            legend(app.UIAxes_2,[num2str(v_phase1),' V'],[num2str(v_phase2),' V'],[num2str(v_phase3),' V'],[num2str(v_phase4),' V'],[num2str(v_phase5),' V'],'Location','northwest');
        end

        % Button pushed function: AnalyzeButton_3
        function AnalyzeButton_3Pushed(app, event)
            r1 = app.R1EditField_3.Value;                % Stator resistance
            x1 = app.X1EditField_3.Value;              % Stator reactance
            r2 = app.R2EditField_2.Value;             % Rotor resistance
            x2 = app.X2EditField_3.Value;              % Rotor reactance
        
            v_phase1 = app.AppliedLineVoltageEditField.Value;    % line voltage
            
            f1 = app.FrequencyEditField.Value;
            f2=app.EditField_5.Value;
            f3=app.EditField_6.Value;
            f4= app.EditField_7.Value;
            f5= app.EditField_8.Value;
            
            Poles= app.PolesEditField.Value;


            Ls=x1/(2*pi*f1);
            Lr=x2/(2*pi*f1);
            Vlnf1=v_phase1/(3^0.5);
            Vlnf2=Vlnf1*f2/f1;
            Vlnf3=Vlnf1*f3/f1;
            Vlnf4=Vlnf1*f4/f1;
            Vlnf5=Vlnf1*f5/f1;
            Wsync1=4*3.1416*f1/Poles;
            Wsync2=4*3.1416*f2/Poles;
            Wsync3=4*3.1416*f3/Poles;
            Wsync4=4*3.1416*f4/Poles;
            Wsync5=4*3.1416*f5/Poles;
            Tmf2=zeros(round(Wsync2)*500+10,1);
            Tmf3=zeros(round(Wsync3)*500+10,1);
            Tmf4=zeros(round(Wsync4)*500+10,1);
            Tmf5=zeros(round(Wsync5)*500+10,1);
            Tmf1=zeros(round(Wsync1)*500+1,1);
            m=1;
            for Wrotor1=0:0.002:Wsync1
                Tmf1(m)=(3*(((Vlnf1^2)*r2/((Wsync1-Wrotor1)/Wsync1))/((r1+r2/((Wsync1-Wrotor1)/Wsync1))^2+(2*pi*f1*Ls+2*pi*f1*Lr)^2))/Wsync1); %star connected
                m=m+1;
            end
            m=1;
            for Wrotor2=0:0.002:Wsync2
                Tmf2(m)=(3*(((Vlnf2^2)*r2/((Wsync2-Wrotor2)/Wsync2))/((r1+r2/((Wsync2-Wrotor2)/Wsync2))^2+(2*pi*f2*Ls+2*pi*f2*Lr)^2))/Wsync2);
                m=m+1;
            end
            m=1;
            for Wrotor3=0:0.002:Wsync3
                Tmf3(m)=(3*(((Vlnf3^2)*r2/((Wsync3-Wrotor3)/Wsync3))/((r1+r2/((Wsync3-Wrotor3)/Wsync3))^2+(2*pi*f3*Ls+2*pi*f3*Lr)^2))/Wsync3);
                m=m+1;
            end
            m=1;
            for Wrotor4=0:0.002:Wsync4
                Tmf4(m)=(3*(((Vlnf4^2)*r2/((Wsync4-Wrotor4)/Wsync4))/((r1+r2/((Wsync4-Wrotor4)/Wsync4))^2+(2*pi*f4*Ls+2*pi*f4*Lr)^2))/Wsync4);
                m=m+1;
            end
            m=1;
            for Wrotor5=0:0.002:Wsync5
                Tmf5(m)=(3*(((Vlnf5^2)*r2/((Wsync5-Wrotor5)/Wsync5))/((r1+r2/((Wsync5-Wrotor5)/Wsync5))^2+(2*pi*f5*Ls+2*pi*f5*Lr)^2))/Wsync5);
                m=m+1;
            end
            plot(app.UIAxes_3,Tmf1,'r','Linewidth',2);
            hold(app.UIAxes_3,"on");
            plot(app.UIAxes_3,Tmf2,'k','Linewidth',2);
            plot(app.UIAxes_3,Tmf3,'b','Linewidth',2);
            plot(app.UIAxes_3,Tmf4,'g','Linewidth',2);
            plot(app.UIAxes_3,Tmf5,'c','Linewidth',2);
            hold(app.UIAxes_3,"off");
            ylabel(app.UIAxes_3,'Torque(N-m)');
            xlabel(app.UIAxes_3,'Rotor Speed(Rad/s) * 100');
                     
            legend(app.UIAxes_3,[num2str(f1),' Hz'],[num2str(f2),' Hz'],[num2str(f3),' Hz'],[num2str(f4),' Hz'],[num2str(f5),' Hz'],'Location','northwest')
        end

        % Button pushed function: AnalyzeButton_4
        function AnalyzeButton_4Pushed(app, event)
            Vratedph= app.AppliedLineVoltageEditField.Value;
            P= app.PolesEditField.Value;
            Rs= app.R1EditField_4.Value;
            Rr= app.R2EditField_3.Value;
            Xs= app.X1EditField_4.Value;
            Xr= app.X2EditField_4.Value;
            
            Tmm=[];
            Wrotormat=[];
            Ls=Xs/(2*pi*50);
            Lr=Xr/(2*pi*50);
            Vfratio=Vratedph/200;
            Tlstarting= app.StartingTorqueNmEditField.Value;
            Wref= app.ReferenceSpeedRPMEditField.Value;
            
            if Tlstarting==0
                
                w_sync=Wref;
                f=w_sync*P/120;
                V=Vfratio*f;
            end
            
            if Tlstarting~=0
                for f=1:0.001:200
                    
                    w_sync=120*f/P;
                    s=(w_sync-Wref)/w_sync;
                    sm=(Rr/((Rs^2+(2*pi*f*Ls+2*pi*f*Lr)^2)^0.5));
                    
                    if Wref<w_sync && s<sm
                        F=f;
                        V=Vfratio*F;
                        
                        if Tlstarting>=(0.95*(3*(((V^2)*Rr/((w_sync-Wref)/w_sync))/((Rs+Rr/((w_sync-Wref)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync)) && Tlstarting<=(1.05*(3*(((V^2)*Rr/((w_sync-Wref)/w_sync))/((Rs+Rr/((w_sync-Wref)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync))
                            break;
                        end
                    end
                end
            end
            Tm=zeros(100001,1);
            Wrot=zeros(100001,1);
            m=1;
            
            
            for Wrotor=0:w_sync/100000:w_sync
                Tm(m)=(3*(((V^2)*Rr/((w_sync-Wrotor)/w_sync))/((Rs+Rr/((w_sync-Wrotor)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync);
                if Tm(m)<0
                    Tm(m)=0;
                end
                Wrot(m)=Wrotor;
                m=m+1;
            end
            Tmax=(3*(V^2/(Rs+(Rs^2+(2*pi*f*Ls+2*pi*f*Lr)^2)^0.5))/(2*w_sync));
            for Tl=0.1:Tmax/10:Tmax
                
                Tmm=[Tmm Tm];
                Wrotormat=[Wrotormat Wrot];
                for a=100001:-1:1
                    
                    if (Tm(a)*0.95)<=Tl && Tl<=(Tm(a)*1.05)
                        W=Wrot(a);
                        break;
                    end
                end
                
                Werror=Wref-W;
                n=1;
                
                f=f+(Werror*P/120);
                
                w_sync=120*f/P;
                
                V=Vfratio*f;
                
                for Wrotor=0:w_sync/100000:w_sync
                    
                    Tm(n)=(3*(((V^2)*Rr/((w_sync-Wrotor)/w_sync))/((Rs+Rr/((w_sync-Wrotor)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync); %Star connected
                    if Tm(n)<0
                        Tm(n)=0;
                    end
                    Wrot(n)=Wrotor;
                    n=n+1;
                end
            end
            
            [c,d]=size(Tmm);
            [e,h]=size(Wrotormat);


            for g=1:1:d
                plot(app.UIAxes_4,Wrotormat(:,g),Tmm(:,g));
                hold(app.UIAxes_4,"on")
            end
            hold(app.UIAxes_4,"off");
        
        end

        % Button pushed function: AnalyzeButton_5
        function AnalyzeButton_5Pushed(app, event)
            %Motor detalis (Rated)
            P = app.RatedPowerEditField.Value * 10^-3 %power
            V = app.AppliedLineVoltageEditField.Value;    %line voltage
            Va = V/sqrt(3) ; %phase voltage
            fe = app.FrequencyEditField.Value;       %frequency
            We = 2*pi*fe;   %angular velocity
            Lambda = sqrt(2)*Va/We;
            I= P/(sqrt(3)*V) ; %line current
            Ipeakbase = sqrt(2)*I; %peak value of current
            poles= app.PolesEditField.Value ;   %number of poles
            %Paramteres for 50-Hz motor
            VI0 = V/sqrt(3) ;
            X10 = app.X1EditField_5.Value;
            X20 = app.X2EditField_5.Value;
            Xm0 = app.XmEditField_3.Value;
            R1 = app.R1EditField_5.Value;
            R2 = app.R2EditField_4.Value;
            %d-q parameters
            Lm = Xm0/We;
            LS = Lm + X10/We;
            LR = Lm + X20/We;
            Ra = R1;
            RaR = R2 ;
            n=1;
            % Operating point
            Wm = 2*n*pi/60;
            Wme = (poles/2)*Wm;
            Pmech = 9.7*10-3;
            Tmech = Pmech/Wm;
            Te=zeros(1001,1);
            for n = 1:1:41
                lambda_D_R = (0.8 + (n-1)*0.4/40)*Lambda;
                iQ(n) = (2/3) * (2/poles) * (LR/Lm) * (Tmech/lambda_D_R) ;
                iD(n) = (lambda_D_R/Lm) ;
                iQm(n) = - (Lm/LR)*iQ(n);
                Ia(n) =sqrt((iD(n)^2 + iQ(n)^2)/2) ;
                We(n) = Wme - (RaR/LR)*(iQ(n)/iD(n)) ;
                fe(n) = We(n)*poles/120 ;
                Varms(n) = sqrt( ((Ra*iD(n)-We(n)*(LS-Lm^2/LR)*iQ(n)) ^2 +(Ra*iQ(n)+ We(n)*LS*iD(n))^2) /2) ;
            end
            plot (app.UIAxes_5,Varms,iQ,'r','Linewidth',2);
            %axis([100 160 8 48]);
            plot(app.UIAxes_6,Varms,iD,'m','Linewidth',2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Analysis of Torque-Speed Characteristics using  Different Speed Control Methods'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'EXIT';
            app.AppliedLineVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.AppliedLineVoltageEditFieldLabel.Text = 'Applied Line Voltage';
            app.AppliedLineVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.AppliedLineVoltageEditField.Value = 208;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 50;
            app.PolesEditFieldLabel = uilabel(app.UIFigure);
            app.PolesEditFieldLabel.Text = 'Poles';
            app.PolesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PolesEditField.Value = 4;
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField.Value = 0.243;
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.Value = 0.67;
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.Value = 0.67;
            app.XmEditFieldLabel = uilabel(app.UIFigure);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmEditField.Value = 14.03;
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.R1EditField_2Label = uilabel(app.UIFigure);
            app.R1EditField_2Label.Text = 'R1';
            app.R1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField_2.Value = 0.243;
            app.X1EditField_2Label = uilabel(app.UIFigure);
            app.X1EditField_2Label.Text = 'X1';
            app.X1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_2.Value = 0.67;
            app.X2EditField_2Label = uilabel(app.UIFigure);
            app.X2EditField_2Label.Text = 'X2';
            app.X2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_2.Value = 0.67;
            app.XmEditField_2Label = uilabel(app.UIFigure);
            app.XmEditField_2Label.Text = 'Xm';
            app.XmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XmEditField_2.Value = 14.03;
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField.Value = 0.151;
            app.TestingStatorVoltagesVLabel = uilabel(app.UIFigure);
            app.TestingStatorVoltagesVLabel.Text = 'Testing Stator Voltages(V)';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 210;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 220;
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 215;
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 240;
            app.AnalyzeButton_2 = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed, true);
            app.AnalyzeButton_2.Text = 'Analyze';
            app.R1EditField_3Label = uilabel(app.UIFigure);
            app.R1EditField_3Label.Text = 'R1';
            app.R1EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField_3.Value = 0.243;
            app.X1EditField_3Label = uilabel(app.UIFigure);
            app.X1EditField_3Label.Text = 'X1';
            app.X1EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_3.Value = 0.67;
            app.X2EditField_3Label = uilabel(app.UIFigure);
            app.X2EditField_3Label.Text = 'X2';
            app.X2EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_3.Value = 0.67;
            app.R2EditField_2Label = uilabel(app.UIFigure);
            app.R2EditField_2Label.Text = 'R2';
            app.R2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField_2.Value = 0.151;
            app.TestingFrequenciesHzLabel = uilabel(app.UIFigure);
            app.TestingFrequenciesHzLabel.Text = 'Testing Frequencies(Hz)';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 50;
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 60;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Value = 70;
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Value = 80;
            app.AnalyzeButton_3 = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_3Pushed, true);
            app.AnalyzeButton_3.Text = 'Analyze';
            app.TestingRotorResistanceLabel = uilabel(app.UIFigure);
            app.TestingRotorResistanceLabel.Text = 'Testing Rotor Resistance';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Value = 0.151;
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Value = 0.1;
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.Value = 0.5;
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.Value = 0.7;
            app.AnalyzeButton_4 = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton_4.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_4Pushed, true);
            app.AnalyzeButton_4.Text = 'Analyze';
            app.R1EditField_4Label = uilabel(app.UIFigure);
            app.R1EditField_4Label.Text = 'R1';
            app.R1EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField_4.Value = 0.075;
            app.X1EditField_4Label = uilabel(app.UIFigure);
            app.X1EditField_4Label.Text = 'X1';
            app.X1EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_4.Value = 0.45;
            app.X2EditField_4Label = uilabel(app.UIFigure);
            app.X2EditField_4Label.Text = 'X2';
            app.X2EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_4.Value = 0.45;
            app.StartingTorqueNmEditFieldLabel = uilabel(app.UIFigure);
            app.StartingTorqueNmEditFieldLabel.Text = 'Starting Torque(N-m)';
            app.StartingTorqueNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingTorqueNmEditField.Value = 1.5;
            app.R2EditField_3Label = uilabel(app.UIFigure);
            app.R2EditField_3Label.Text = 'R2';
            app.R2EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField_3.Value = 0.1;
            app.ReferenceSpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.ReferenceSpeedRPMEditFieldLabel.Text = 'Reference Speed(RPM)';
            app.ReferenceSpeedRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferenceSpeedRPMEditField.Value = 500;
            app.R1EditField_5Label = uilabel(app.UIFigure);
            app.R1EditField_5Label.Text = 'R1';
            app.R1EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField_5.Value = 0.075;
            app.X1EditField_5Label = uilabel(app.UIFigure);
            app.X1EditField_5Label.Text = 'X1';
            app.X1EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_5.Value = 0.45;
            app.X2EditField_5Label = uilabel(app.UIFigure);
            app.X2EditField_5Label.Text = 'X2';
            app.X2EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_5.Value = 0.45;
            app.R2EditField_4Label = uilabel(app.UIFigure);
            app.R2EditField_4Label.Text = 'R2';
            app.R2EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField_4.Value = 0.1;
            app.RatedPowerEditFieldLabel = uilabel(app.UIFigure);
            app.RatedPowerEditFieldLabel.Text = 'Rated Power';
            app.RatedPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnalyzeButton_5 = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton_5.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_5Pushed, true);
            app.AnalyzeButton_5.Text = 'Analyze';
            app.XmEditField_3Label = uilabel(app.UIFigure);
            app.XmEditField_3Label.Text = 'Xm';
            app.XmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XmEditField_3.Value = 14.03;
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Variable Rotor Resistance Method')
            xlabel(app.UIAxes, 'Rotor Speed (RPM)')
            ylabel(app.UIAxes, 'Torque (N-m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, ' Variable Stator Voltage Method')
            xlabel(app.UIAxes_2, 'Rotor Speed(RPM)')
            ylabel(app.UIAxes_2, 'Torque(N-m)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Constant V/FControl Method')
            xlabel(app.UIAxes_3, 'Rotor Speed(RPM)')
            ylabel(app.UIAxes_3, 'Torque(N-m)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Closed Loop V/f Speed Control Method')
            xlabel(app.UIAxes_4, 'Rotor Speed(RPM)')
            ylabel(app.UIAxes_4, 'Torque(N-m)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Graph of Q axis Current vs Stator Voltage')
            xlabel(app.UIAxes_5, 'Va(V)')
            ylabel(app.UIAxes_5, 'IQ(A)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Graph of D axis Current vs Stator Voltage')
            xlabel(app.UIAxes_6, 'Va(V)')
            ylabel(app.UIAxes_6, 'ID(A)')
            zlabel(app.UIAxes_6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpeedCtrl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end