classdef debug < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnalyzeButton                   matlab.ui.control.Button
        R1EditFieldLabel                matlab.ui.control.Label
        R1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        StartingTorqueNmEditFieldLabel  matlab.ui.control.Label
        StartingTorqueNmEditField       matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        R2EditField                     matlab.ui.control.NumericEditField
        ReferenceSpeedRPMEditFieldLabel  matlab.ui.control.Label
        ReferenceSpeedRPMEditField      matlab.ui.control.NumericEditField
        AppliedLineVoltageEditFieldLabel  matlab.ui.control.Label
        AppliedLineVoltageEditField     matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        PolesEditFieldLabel             matlab.ui.control.Label
        PolesEditField                  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            Vratedph= app.AppliedLineVoltageEditField.Value;
            P= app.PolesEditField.Value;
            Rs= app.R1EditField.Value;
            Rr= app.R2EditField.Value;
            Xs= app.X1EditField.Value;
            Xr= app.X2EditField.Value;
            
            Tmm=[];
            Wrotormat=[];
            Ls=Xs/(2*pi*50);
            Lr=Xr/(2*pi*50);
            Vfratio=Vratedph/200;
            Tlstarting= app.StartingTorqueNmEditField.Value;
            Wref= app.ReferenceSpeedRPMEditField.Value;
            
            if Tlstarting==0
                
                w_sync=Wref;
                f=w_sync*P/120;
                V=Vfratio*f;
            end
            
              if Tlstarting~=0
                for f=1:0.001:200
                    
                    w_sync=120*f/P;
                    s=(w_sync-Wref)/w_sync;
                    sm=(Rr/((Rs^2+(2*pi*f*Ls+2*pi*f*Lr)^2)^0.5));
                    
                    if Wref<w_sync && s<sm
                        F=f;
                        V=Vfratio*F;
                        
                        if Tlstarting>=(0.95*(3*(((V^2)*Rr/((w_sync-Wref)/w_sync))/((Rs+Rr/((w_sync-Wref)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync)) && Tlstarting<=(1.05*(3*(((V^2)*Rr/((w_sync-Wref)/w_sync))/((Rs+Rr/((w_sync-Wref)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync))
                            break;
                        end
                    end
                end
            end
            Tm=zeros(100001,1);
            Wrot=zeros(100001,1);
            m=1;
            
            
            for Wrotor=0:w_sync/100000:w_sync
                Tm(m)=(3*(((V^2)*Rr/((w_sync-Wrotor)/w_sync))/((Rs+Rr/((w_sync-Wrotor)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync);
                if Tm(m)<0
                    Tm(m)=0;
                end
                Wrot(m)=Wrotor;
                m=m+1;
            end
            Tmax=(3*(V^2/(Rs+(Rs^2+(2*pi*f*Ls+2*pi*f*Lr)^2)^0.5))/(2*w_sync));
            for Tl=0.1:Tmax/10:Tmax
                
                Tmm=[Tmm Tm];
                Wrotormat=[Wrotormat Wrot];
                for a=100001:-1:1
                    
                    if (Tm(a)*0.95)<=Tl && Tl<=(Tm(a)*1.05)
                        W=Wrot(a);
                        break;
                    end
                end
                
                Werror=Wref-W;
                n=1;
                
                f=f+(Werror*P/120);
                
                w_sync=120*f/P;
                
                V=Vfratio*f;
                
                for Wrotor=0:w_sync/100000:w_sync
                    
                    Tm(n)=(3*(((V^2)*Rr/((w_sync-Wrotor)/w_sync))/((Rs+Rr/((w_sync-Wrotor)/w_sync))^2+(2*pi*f*Ls+2*pi*f*Lr)^2))/w_sync); %Star connected
                    if Tm(n)<0
                        Tm(n)=0;
                    end
                    Wrot(n)=Wrotor;
                    n=n+1;
                end
            end
            
            [c,d]=size(Tmm);
            [e,h]=size(Wrotormat);
           
            
            hold(app.UIAxes,"on")
            for g=1:1:d
                plot(app.UIAxes,Wrotormat(:,g),Tmm(:,g));
            end
            hold(app.UIAxes,"off");


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField.Value = 0.075;
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.Value = 0.45;
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.Value = 0.45;
            app.StartingTorqueNmEditFieldLabel = uilabel(app.UIFigure);
            app.StartingTorqueNmEditFieldLabel.Text = 'Starting Torque(N-m)';
            app.StartingTorqueNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingTorqueNmEditField.Value = 1.5;
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField.Value = 0.1;
            app.ReferenceSpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.ReferenceSpeedRPMEditFieldLabel.Text = 'Reference Speed(RPM)';
            app.ReferenceSpeedRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferenceSpeedRPMEditField.Value = 500;
            app.AppliedLineVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.AppliedLineVoltageEditFieldLabel.Text = 'Applied Line Voltage';
            app.AppliedLineVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.AppliedLineVoltageEditField.Value = 240;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 50;
            app.PolesEditFieldLabel = uilabel(app.UIFigure);
            app.PolesEditFieldLabel.Text = 'Poles';
            app.PolesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PolesEditField.Value = 4;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Closed Loop V/f Speed Control Method')
            xlabel(app.UIAxes, 'Rotor Speed(RPM)')
            ylabel(app.UIAxes, 'Torque(N-m)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = debug

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end