classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MassEditFieldLabel      matlab.ui.control.Label
        MassEditField           matlab.ui.control.NumericEditField
        accEditFieldLabel       matlab.ui.control.Label
        accEditField            matlab.ui.control.NumericEditField
        CalculateButton         matlab.ui.control.Button
        OperationDropDownLabel  matlab.ui.control.Label
        OperationDropDown       matlab.ui.control.DropDown
        OutputEditFieldLabel    matlab.ui.control.Label
        OutputEditField         matlab.ui.control.NumericEditField
        WorkButton              matlab.ui.control.Button
        DemoButton              matlab.ui.control.Button
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        Button                  matlab.ui.control.Button
        ShowEditFieldLabel      matlab.ui.control.Label
        ShowEditField           matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        force; % Description
        yn;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            op = app.OperationDropDown.Value;
            Mass = app.MassEditField.Value;
            acc = app.accEditField.Value;
            if strcmp(op,'Mul')
                app.OutputEditField.Value =Mass*acc;
                app.force = Mass*acc;
            
            end
            if strcmp(op,'div')
               
                app.OutputEditField.Value = Mass/acc;
                app.force = Mass/acc;
            end
            app.yn=app.OperationDropDown.Value;
                     
            
        end

        % Button pushed function: WorkButton
        function WorkButtonPushed(app, event)
            demo2(app);
        end

        % Button pushed function: DemoButton
        function DemoButtonPushed(app, event)
            run('E:\MATLAB CODES\grabit.m')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            z=-2+3i;
            z = z/abs(z);
            quiver(app.UIAxes,0,0,real(z),imag(z),1);
            hold(app.UIAxes);
            z2 = -5 - 3j;
            z2 = z2/abs(z2);
            quiver(app.UIAxes,0,0,real(z2),imag(z2),1);
            
            z3 = 3 + 6j;
            z3 = z3/abs(z3);
            quiver(app.UIAxes,real(z2),imag(z2),real(z3),imag(z3),1,'r','Linewidth',2);
            
            legend(app.UIAxes,'a','b','c');
              
            
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.accEditFieldLabel = uilabel(app.UIFigure);
            app.accEditFieldLabel.Text = 'acc';
            app.accEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.UIFigure);
            app.OperationDropDown.Items = {'Mul', 'div'};
            app.OperationDropDown.Value = 'Mul';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.WorkButton = uibutton(app.UIFigure, 'push');
            app.WorkButton.ButtonPushedFcn = createCallbackFcn(app, @WorkButtonPushed, true);
            app.WorkButton.Text = 'Work';
            app.DemoButton = uibutton(app.UIFigure, 'push');
            app.DemoButton.ButtonPushedFcn = createCallbackFcn(app, @DemoButtonPushed, true);
            app.DemoButton.Text = 'Demo';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ShowEditFieldLabel = uilabel(app.UIFigure);
            app.ShowEditFieldLabel.Text = 'Show';
            app.ShowEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end