classdef demo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DisplacementEditFieldLabel  matlab.ui.control.Label
        DisplacementEditField       matlab.ui.control.NumericEditField
        CalculateButton             matlab.ui.control.Button
        WorkEditFieldLabel          matlab.ui.control.Label
        WorkEditField               matlab.ui.control.NumericEditField
        WEditFieldLabel             matlab.ui.control.Label
        WEditField                  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        demoapp; % Description
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, demo)
            app.demoapp = demo;
            dis = 50;
            app.DisplacementEditField.Value= dis;
            Force = app.demoapp.force;
            if strcmp(app.demoapp.yn,'Mul')
              work = Force*dis;  
            end
            if strcmp(app.demoapp.yn,'div')
              work = Force/dis;  
            end
    
            app.WorkEditField.Value = work;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
%             dis = app.DisplacementEditField.Value;
%             Force = app.demoapp.force;
%             if strcmp(app.demoapp.yn,'Mul')
%               work = Force*dis;  
%             end
%             if strcmp(app.demoapp.yn,'div')
%               work = Force/dis;  
%             end
%     
%             app.WorkEditField.Value = work;
        end

        % Value changed function: WorkEditField
        function WorkEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: DisplacementEditField
        function DisplacementEditFieldValueChanged(app, event)
           
            
        end

        % Value changed function: WEditField
        function WEditFieldValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisplacementEditFieldLabel = uilabel(app.UIFigure);
            app.DisplacementEditFieldLabel.Text = 'Displacement';
            app.DisplacementEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplacementEditField.ValueChangedFcn = createCallbackFcn(app, @DisplacementEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.WorkEditFieldLabel = uilabel(app.UIFigure);
            app.WorkEditFieldLabel.Text = 'Work';
            app.WorkEditField = uieditfield(app.UIFigure, 'numeric');
            app.WorkEditField.ValueChangedFcn = createCallbackFcn(app, @WorkEditFieldValueChanged, true);
            app.WEditFieldLabel = uilabel(app.UIFigure);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.UIFigure, 'text');
            app.WEditField.ValueChangedFcn = createCallbackFcn(app, @WEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end